classdef projet_utilitaire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fen                            matlab.ui.Figure
        tableauM                       matlab.ui.container.TabGroup
        tabMath                        matlab.ui.container.Tab
        TypedoperationsMat             matlab.ui.control.Label
        typeOperation                  matlab.ui.control.DropDown
        trace_det_trans                matlab.ui.container.Panel
        resultat_trace_det_tranc       matlab.ui.container.Panel
        determinant                    matlab.ui.container.Panel
        traces                         matlab.ui.container.Panel
        tansBtn                        matlab.ui.control.Button
        detBtn                         matlab.ui.control.Button
        transposee                     matlab.ui.container.Panel
        traceBtn                       matlab.ui.control.Button
        detSource                      matlab.ui.container.Panel
        determinant_2                  matlab.ui.container.Panel
        NombredecolonneLabel_2         matlab.ui.control.Label
        detCol                         matlab.ui.control.Spinner
        NombredeligneLabel             matlab.ui.control.Label
        detLign                        matlab.ui.control.Spinner
        validTrans                     matlab.ui.control.Button
        sommProduitmat                 matlab.ui.container.Panel
        premierMat                     matlab.ui.container.Panel
        secondMat                      matlab.ui.container.Panel
        btnProduit                     matlab.ui.control.Button
        btnSomme                       matlab.ui.control.Button
        resultProd                     matlab.ui.container.Panel
        resultSom                      matlab.ui.container.Panel
        DimensionPremirematricePanel   matlab.ui.container.Panel
        LigneLabel                     matlab.ui.control.Label
        lign_mat1                      matlab.ui.control.Spinner
        ColonneLabel                   matlab.ui.control.Label
        col_mat1                       matlab.ui.control.Spinner
        validMat1                      matlab.ui.control.Button
        DimensionsecondMatricePanel    matlab.ui.container.Panel
        LigneLabel_2                   matlab.ui.control.Label
        lign_mat2                      matlab.ui.control.Spinner
        ColonneLabel_2                 matlab.ui.control.Label
        col_mat2                       matlab.ui.control.Spinner
        validMat2                      matlab.ui.control.Button
        decoMat                        matlab.ui.container.Panel
        decoSource                     matlab.ui.container.Panel
        NombredeligneSpinner_4Label_4  matlab.ui.control.Label
        decoLign                       matlab.ui.control.Spinner
        NombredecolonneSpinner_4Label_4  matlab.ui.control.Label
        decoCol                        matlab.ui.control.Spinner
        ordreDeco                      matlab.ui.control.Button
        decoSvd                        matlab.ui.control.Button
        decoNorm                       matlab.ui.control.Button
        decoResult                     matlab.ui.container.Panel
        norme                          matlab.ui.container.Panel
        singuliere                     matlab.ui.container.Panel
        btnCh                          matlab.ui.control.Button
        btnQR                          matlab.ui.control.Button
        resCh                          matlab.ui.container.Panel
        resQR                          matlab.ui.container.Panel
        ValQ                           matlab.ui.container.Panel
        ValR                           matlab.ui.container.Panel
        eqLineaire                     matlab.ui.container.Panel
        eqSourceMat                    matlab.ui.container.Panel
        eqSecond                       matlab.ui.container.Panel
        eqResultBtn                    matlab.ui.control.Button
        eqOrdreBtn                     matlab.ui.control.Button
        eqResultat                     matlab.ui.container.Panel
        eqResultVariable               matlab.ui.container.Panel
        eqResultValeur                 matlab.ui.container.Panel
        eqNomVar                       matlab.ui.container.Panel
        vectSourceMat_3                matlab.ui.container.Panel
        NombredesquationsLabel         matlab.ui.control.Label
        eqLign                         matlab.ui.control.Spinner
        NombredesinconnuesLabel        matlab.ui.control.Label
        eqCol                          matlab.ui.control.Spinner
        vect_val_Mat                   matlab.ui.container.Panel
        vectSourceMat                  matlab.ui.container.Panel
        vectResultat                   matlab.ui.container.Panel
        vectP                          matlab.ui.container.Panel
        valP                           matlab.ui.container.Panel
        vectResultBtn                  matlab.ui.control.Button
        vectOrdreBtn                   matlab.ui.control.Button
        dimVectP                       matlab.ui.container.Panel
        NombredecolonneSpinner_4Label_5  matlab.ui.control.Label
        vectCol                        matlab.ui.control.Spinner
        NombredeligneSpinner_4Label_5  matlab.ui.control.Label
        vectLign                       matlab.ui.control.Spinner
        invMat                         matlab.ui.container.Panel
        invSourceMat                   matlab.ui.container.Panel
        invResultMat                   matlab.ui.container.Panel
        invResultBtn                   matlab.ui.control.Button
        ValiderButton                  matlab.ui.control.Button
        DimensiondelaMatricePanel      matlab.ui.container.Panel
        NombredeligneSpinnerLabel      matlab.ui.control.Label
        invLign                        matlab.ui.control.Spinner
        NombredecolonneLabel_3         matlab.ui.control.Label
        invColon                       matlab.ui.control.Spinner
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.StateButton
        retournAccueilMath             matlab.ui.control.Button
        panAccueilMat                  matlab.ui.container.Panel
        vectResultat_2                 matlab.ui.container.Panel
        vectSourceMat_8                matlab.ui.container.Panel
        lab4                           matlab.ui.control.Label
        allerAuSignalBtn               matlab.ui.control.Button
        matNB                          matlab.ui.control.TextArea
        vectSourceMat_4                matlab.ui.container.Panel
        vectSourceMat_6                matlab.ui.container.Panel
        matCloseBtn                    matlab.ui.control.Button
        aproposMath                    matlab.ui.control.Button
        aideMath                       matlab.ui.control.Button
        matBienvenu                    matlab.ui.control.TextArea
        vectSourceMat_5                matlab.ui.container.Panel
        tabSign                        matlab.ui.container.Tab
        panMenu                        matlab.ui.container.Panel
        traceFonctionSimple            matlab.ui.control.Button
        transformerFourier             matlab.ui.control.Button
        traceConvolution               matlab.ui.control.Button
        TracagedunBruit                matlab.ui.control.Button
        menuClose                      matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        tracageEchelon                 matlab.ui.control.Button
        aideSignal                     matlab.ui.control.Button
        Tracagedunsignaldedirac        matlab.ui.control.Button
        accueilSignal                  matlab.ui.control.Button
        apropos                        matlab.ui.control.Button
        TraitementdesignalLabel        matlab.ui.control.Label
        labTitre                       matlab.ui.control.Label
        panSimpleFunction              matlab.ui.container.Panel
        FonctionEditFieldLabel         matlab.ui.control.Label
        simpleFonction                 matlab.ui.control.EditField
        axeSimple                      matlab.ui.control.UIAxes
        traceSignalSimple              matlab.ui.control.Button
        BornedelavariablePanel         matlab.ui.container.Panel
        pasSpinnerLabel                matlab.ui.control.Label
        pasInterval                    matlab.ui.control.Spinner
        infSpinnerLabel                matlab.ui.control.Label
        bornInf                        matlab.ui.control.Spinner
        supSpinnerLabel                matlab.ui.control.Label
        bornSup                        matlab.ui.control.Spinner
        transformFourier               matlab.ui.container.Panel
        FonctionEditFieldLabel_5       matlab.ui.control.Label
        fourierFunction                matlab.ui.control.EditField
        fourierTransformebtn           matlab.ui.control.Button
        borneTransFourier              matlab.ui.container.Panel
        pasSpinnerLabel_5              matlab.ui.control.Label
        pasFourier                     matlab.ui.control.Spinner
        infSpinnerLabel_5              matlab.ui.control.Label
        infFourier                     matlab.ui.control.Spinner
        supSpinnerLabel_5              matlab.ui.control.Label
        supFourier                     matlab.ui.control.Spinner
        optionBtn                      matlab.ui.container.ButtonGroup
        dimensionSimple                matlab.ui.control.RadioButton
        deuxDimension                  matlab.ui.control.RadioButton
        nDimension                     matlab.ui.control.RadioButton
        axeOrigineFourier              matlab.ui.control.UIAxes
        axeInversFourier               matlab.ui.control.UIAxes
        axeTransformer                 matlab.ui.control.UIAxes
        signalBruit                    matlab.ui.container.Panel
        FonctionEditFieldLabel_11      matlab.ui.control.Label
        foncSignalBruit                matlab.ui.control.EditField
        traceSignaBruit                matlab.ui.control.Button
        bornBruit                      matlab.ui.container.Panel
        pasSpinnerLabel_10             matlab.ui.control.Label
        t0B                            matlab.ui.control.Spinner
        infSpinnerLabel_10             matlab.ui.control.Label
        amplitudeB                     matlab.ui.control.Spinner
        periodeTBruit                  matlab.ui.control.Label
        periodeB                       matlab.ui.control.Spinner
        axeSignalAvecBruit             matlab.ui.control.UIAxes
        FonctionEditFieldLabel_12      matlab.ui.control.Label
        foncBruit                      matlab.ui.control.EditField
        axeResBruit                    matlab.ui.control.UIAxes
        signalDirac                    matlab.ui.container.Panel
        FonctionEditFieldLabel_13      matlab.ui.control.Label
        foncDirac                      matlab.ui.control.EditField
        tracerDirac                    matlab.ui.control.Button
        BorneDirac                     matlab.ui.container.Panel
        diracLabelT0                   matlab.ui.control.Label
        t0Dirac                        matlab.ui.control.Spinner
        amplitudDiracLabel             matlab.ui.control.Label
        amplitudeDirac                 matlab.ui.control.Spinner
        periodeTDiracLabel             matlab.ui.control.Label
        periodeDirac                   matlab.ui.control.Spinner
        axeDirac                       matlab.ui.control.UIAxes
        diracOptionBouton              matlab.ui.container.ButtonGroup
        optionDirac                    matlab.ui.control.RadioButton
        optionRectagnle                matlab.ui.control.RadioButton
        optionTriangle                 matlab.ui.control.RadioButton
        optionEchelon                  matlab.ui.control.RadioButton
        BorneDirac_2                   matlab.ui.container.Panel
        amplitudDiracLabel_2           matlab.ui.control.Label
        amplitudDiracLabel_3           matlab.ui.control.Label
        amplitudDiracLabel_4           matlab.ui.control.Label
        amplitudDiracLabel_6           matlab.ui.control.Label
        panConvolution                 matlab.ui.container.Panel
        rectangleLabelFunc             matlab.ui.control.Label
        foncConv1                      matlab.ui.control.EditField
        tracerConvolution              matlab.ui.control.Button
        BorneConvolution               matlab.ui.container.Panel
        rectangleLabelT0               matlab.ui.control.Label
        freqConv                       matlab.ui.control.Spinner
        amplitudRectangleLabel         matlab.ui.control.Label
        infConv                        matlab.ui.control.Spinner
        periodeRectangleLabel          matlab.ui.control.Label
        supConv                        matlab.ui.control.Spinner
        rectangleLabelFunc_2           matlab.ui.control.Label
        foncConv2                      matlab.ui.control.EditField
        axeConvolution                 matlab.ui.control.UIAxes
        panSign                        matlab.ui.container.Panel
        functionPeriodique_2           matlab.ui.control.Label
        fonctionSign                   matlab.ui.control.EditField
        signBoutonValider              matlab.ui.control.Button
        signOption                     matlab.ui.control.DropDown
        indication_de_sign             matlab.ui.container.Panel
        LatraagedesignedunsgnalsefaitcommececiLabel  matlab.ui.control.Label
        stsignt1sit0etLabel            matlab.ui.control.Label
        stsignt1sit0Label              matlab.ui.control.Label
        axeSign                        matlab.ui.control.UIAxes
        menuShow                       matlab.ui.control.Button
        panAccueilS                    matlab.ui.container.Panel
        titreSignal                    matlab.ui.container.Panel
        contenu2S                      matlab.ui.container.Panel
        closeSignal                    matlab.ui.control.Button
        allerMath                      matlab.ui.control.Button
        signRetourneAccueil            matlab.ui.control.Button
        labTitre_14                    matlab.ui.control.Label
        contenu1S                      matlab.ui.container.Panel
        vectSourceMat_9                matlab.ui.container.Panel
        lab4_3                         matlab.ui.control.Label
        signalIndication               matlab.ui.control.TextArea
        bienvenuAccueil                matlab.ui.control.TextArea
        tabImg                         matlab.ui.container.Tab
        accueil                        matlab.ui.container.Panel
        accueilP                       matlab.ui.container.Panel
        labAccueil                     matlab.ui.control.Label
        panT                           matlab.ui.container.Panel
        modUtil                        matlab.ui.container.Panel
        lab1_2                         matlab.ui.control.Label
        lab2_2                         matlab.ui.control.Label
        lab4_2                         matlab.ui.control.Label
        lab3_2                         matlab.ui.control.Label
        versionLog                     matlab.ui.control.Label
        principal_Aller_au_Math        matlab.ui.control.Button
        principalAller_auSignal        matlab.ui.control.Button
        principal_Aide                 matlab.ui.control.Button
        principal_Apropos              matlab.ui.control.Button
        quitPrincipalBtn               matlab.ui.control.Button
        titre                          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Matrice,transpos;
        inverse,panelS;
        vector,mat1,mat2;
        srcs,resVarName;
        m,deco,seconde;
        equation,somMat,prodMat,allbouton,allpanel;
        activee = '';
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function [matrice] = new_mat(app,varPanel,background,forground,ln,cl)
            [tmp,a,b,x,y,w,h] = app.positions(varPanel,ln,cl);
            for i = 1:ln
                for j = 1:cl
                    mat(i,j) = uieditfield(varPanel,'numeric','HorizontalAlignment','center');
                    mat(i,j).Position = [ x y w h ];
                    mat(i,j).BackgroundColor = background;
                    mat(i,j).FontColor = forground;
                    x = x + w + a ;
                end
                x = tmp ;
                y = y - ( h + b );
            end
            matrice = mat;
            return
        end        
        
        function [rs] = getMatrice(app,mat,ln,cl)
            try
                for i = 1:ln
                    for j = 1:cl
                        rs(i,j) = mat(i,j).Value;                       
                    end
                end
            catch
                warndlg('Valeur dee la matrice n''est pas valide !','Invalide valeur');
            end
        end        
        
        function [activ] = panelActiver(app,panelActif,source,nbr)
            
            for i = 1 : nbr
                if ( source(i) == panelActif )
                    source(i).Visible = 'on' ;
                    activ = panelActif ;
                else
                    source(i).Visible = 'off' ; 
                end
            end
            return
        end
        
        function actualiser(app,panelCourante)
            if ( panelCourante.Children ~= 0 )
                delete(panelCourante.Children);
            end
        end        
        
        function [reponse] = existe(app,objet)
            if(objet.Children ~= 0)
                reponse = 'true';
            else
                reponse = 'false';
            end
        end        

        function panActiv = testActive(app,panSignal,nombre);
            for i = 1 : 1 : nombre
                if (panSignal(i).Visible == 'on')
                    panActiv = panSignal(i);
                    break;
                end
            end
        end        

        function libererPanel(app,panelActif)
            if(panelActif.Children ~= 0 )
                delete(panelActif.Children);
            end
        end
        
        function [tmp,a,b,x,y,w,h] = positions(app,panelActuel,ligne,colonne)
            wid = panelActuel.Position(3);
            heig = panelActuel.Position(4);
            
            hh = heig / (ligne+1) ;
            if  ligne < 5
                h = hh / 2 ;
                z = h / 2 ;
                b = z / (ligne-1) ;
                y = heig - ((z + (z/2))*(ligne+1)) ;
            else
                h = hh / 2 + (hh/(ligne+1)) ;
                z = h / 2 ;
                b = z / (ligne-1) ;
                y = heig - ((z + (z/2))*(ligne+1) - ((ligne/4)*h)) ;
            end 
            
            w = wid/(colonne+1);
            xx = w / 2;
            k = xx / 2;
            a = k / (colonne-1);
            x = k + (k / 2);
            tmp = x ;            
        end        

        function menuCloses(app,panP,panM,panTitle,principalAccueil,titr)
           
            wp = principalAccueil.Position(3); 
            k = 180;
            px = k/2;
            w = wp - k ;
            
            hp = panP.Position(4);            
            d = titr.Position(2);
            x = titr.Position(4);
            y = panTitle.Position(2);
            z = panP.Position(2);
            
            titr.Position = [87 d 1272 x];
            panTitle.Position = [87 y 1272 53];
            principalAccueil.Position = [87 24 1272 600];
            panM.Position = [2 1 1 hp];
            panP.Position = [2 z 1398 hp];
            
            app.modUtil.Position = [px 145 w 339];
            app.versionLog.Position = [680 62 401 20];
        end
        
        function menuShows(app,panP,panM,panTitle,principalAccueil,titr)
            
            wp = principalAccueil.Position(3); 
            k = 180;
            px = k/2;
            w = wp - k ;
            hp = panP.Position(4);
            x = titr.Position(4);
            y = panTitle.Position(2);
            d = titr.Position(2);
            z = panP.Position(2);
            
            titr.Position = [333 766 1065 58];
            panTitle.Position = [57 y 967 53];
            principalAccueil.Position = [57 24 967 600];
            
            app.modUtil.Position = [px 145 w 339];
            app.versionLog.Position = [508 62 401 20];
            
            panM.Position = [2 2 333 hp];
            panP.Position = [334 z 1065 hp];
        end
        
        function boutonActiver(app,boutonActif,source,nbr)
            
            for i = 1 : nbr
                if ( source(i) == boutonActif )
                    if boutonActif == app.accueilSignal
                        boutonActif.BackgroundColor = [0.05 0.17 0.35] ;
                    else
                        source(i).BackgroundColor = [0 0.65 0.65] ;
                    end
                else
                    source(i).BackgroundColor = [0.05 0.17 0.35] ;
                end
            end
        end        

        function selectTableau(app)
            if ( app.tableauM.SelectedTab == app.tabSign )
                app.panAccueilS.Position = [72 2 1328 763]; 
            end
        end 
        
        run(selectTableau);

        function [res] = dim2(app,v,fonc)
	    N = length(v);	
            for (i = 1 : N)
                for(j = 1 : N)
                    xx = v(i);
                    yy = v(j);
                    t(i,j) = xx * yy ;
                end
            end
            for (i = 1 : N)
                for(j = 1 : N)
                    xx = fonc(i);
                    yy = fonc(j);
                    res(i,j) = xx * yy ;
                end
            end
        end
        
        function dirak = diracs(app,f,x)
            for i = 1 :length(x)
                if f(i) > 0
                    k(i) = f(i);
                else
                    k(i) = 0;
                end
            end
            dirak = k;
        end
        
        function rec = rectangle(app,f,x)
            l = length(x);
            for i = 1 :l
                if (x(i) >= -0.5 && x(i) <= 0.5 )
                    f(i) = 1;
                else
                    f(i) = 0;
                end
            end
            rec = f;
        end
        
    end


    methods (Access = private)

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.fen);
        end

        % Callback function
        function QUITTERButtonPushed(app, event)
           close(app.fen);
        end

        % Button pushed function: TracagedunBruit
        function TracagedunBruitButtonPushed(app, event)
            app.labTitre.Text = 'Traçage d''un signal avec le Bruit';           
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.TracagedunBruit,app.allbouton,6);
            app.activee = app.panelActiver(app.signalBruit,app.allpanel,7);
            
        end

        % Callback function
        function TracagedunesimplefonctionButtonPushed(app, event)
            app.labTitre.Text = 'Traçage d''une fonction simple';
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.traceFonctionSimple,app.allbouton,6);
            app.activee = app.panelActiver(app.panSimpleFunction,app.allpanel,7);
        end

        % Callback function
        function TracagedunsignaldediracPushed(app, event)
            app.labTitre.Text = 'Signal de Dirac, Rectangle, Triangle,Echelon, Ramp';
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.Tracagedunsignaldedirac,app.allbouton,6);
            app.activee = app.panelActiver(app.signalDirac,app.allpanel,7);
        end

        % Callback function
        function TracagedunsignaltriangulaireButtonPushed(app, event)
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.Tracagedunsignaltriangulaire,app.allbouton,6);
            app.activee = app.panelActiver(app.panAccueilS,app.allpanel,7);
        end

        % Callback function
        function ValiderButtonPushed(app, event)
            cl = app.invColon.Value;
            ln = app.invLign.Value;
            disp(cl);
            if ln == cl
                app.actualiser(app.invSourceMat);
                app.srcs = app.new_mat(app.invSourceMat,[0.2 0.42 0.52],[0.83 0.94 0.95],ln,cl);
                app.actualiser(app.invResultMat);
            else
                errordlg('Le nombre de ligne et nombre de colonne doivent etres égale','Erreur de dimension');
            end
        end

        % Button pushed function: accueilSignal
        function accueilSignalButtonPushed(app, event)
            app.labTitre.Text = 'Page d''accueil du Signal';
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.activee = app.panelActiver(app.panAccueilS,app.allpanel,7);
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon]; 
            app.boutonActiver(app.accueilSignal,app.allbouton,6) ;
        end

        % Button pushed function: Button, aideMath
        function aideMathButtonPushed(app, event)
            aideMaths = 'Dans cette partie vous calcule le matrice \n Donc il faut avoir de connaissance au moins sur le matrice et il faut pas surtout de faire n''import quoi la dessu\n sinon vous aurez un belle petite erreur !\n Vous devez aussi de respecter le reglement de calcul sur la matrice';
            helpdlg(aideMaths,'Aide sur la matrice');
        end

        % Button pushed function: aideSignal
        function aideSignalButtonPushed(app, event)
            l1 = '   ~~~~~~~~~~ Aide sur le traitement de signal ~~~~~~~~~~';
            l2 = '         ____________________________________________';
            l3 = ' Dans cette partie on manipule le signal en tapant le fonction dans la zone de saisie puis valider par un bouton ';
            l4 = ' La validation de la fonction sur la zone de saisie dépant l''écriture correct puis  le nom du variable.';
            l5 = ' Pour éviter les erreurs  sur le fonction, nous devons utilisé un seul unique variable pour tous les options.';
            l6 = 'Alors utiliser le caractère t pour le nom de la variable';
            l7 = ' ~~~~~~~~~~~~~~~~~~~~~~~~~   NB  ~~~~~~~~~~~~~~~~~~~~~~~~~';
            l8 = 'Sur le traçage d''un signal de Dirac, rectangle, triangle ou Echelon, utiliser sinplement le nom du fonction par Exemple : sin(t) ou t simplement au lieu de dirac(sin(t)) ou dirac(t) ou triplus(t) pour le triangle !';
            aide = sprintf('\n\t %s\n\t%s\n\n\t\t\t\t\t%s \n\t%s\n\t%s\n\n\t%s\n\n\t%s\n\n\t%s', l1,l2,l3, l4,l5,l6,l7,l8);
            helpdlg(aide,'Aide pour Signal');
        end

        % Button pushed function: allerAuSignalBtn, 
        % principalAller_auSignal
        function allerAuSignalBtnButtonPushed(app, event)
            app.tableauM.Visible = 'on';
            app.accueil.Visible = 'off';
            app.panAccueilS.Visible = 'on';
            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.activee = app.panelActiver(app.panAccueilS,app.allpanel,7);
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon]; 
            app.boutonActiver(app.accueilSignal,app.allbouton,6) ;
            app.panMenu.Visible = 'off';
            app.menuShow.Visible = 'on';
            app.tableauM.SelectedTab = app.tabSign ;
            app.panAccueilS.Position = [72 2 1328 763]; 
        end

        % Button pushed function: allerMath, principal_Aller_au_Math
        function allerMathButtonPushed(app, event)
            app.tableauM.Visible = 'on';
            app.tableauM.SelectedTab = app.tabMath;
            app.panAccueilMat.Visible = 'on';
            app.accueil.Visible = 'off';
        end

        % Callback function
        function btnChButtonPushed(app, event)
            
            cl = app.decoCol.Value;
            ln = app.decoLign.Value;
            app.actualiser(app.resCh);
            
            if ( app.decoSource.Children ~= 0 )
                
                if ln == cl
                    symetrique = 'true';
                    cholesky = app.getMatrice(app.deco,ln,cl);
                    fprintf("  ==== La determinatnt dela matrice pour Cholesky est : %f \n",det(cholesky));
                     try                        
                         [l,c] = size(cholesky);
                        for x = 1 : l
                            for y =  1 : c
                                while cholesky(x,y) ~= cholesky(y,x)
                                    symetrique = 'false';
                                    break ;
                                end
                            end
                        end
                        if strcmp(symetrique,'false')
                            warndlg('Oups !!... , cette matrice n''est pas symetrique !','Symetric error ');
                        else
                            ch = chol(cholesky);
                            fprintf('\n =============   Cholesky matrix  ============== \n');
                            disp(ch);
                            [lign,col] = size(ch);
                            [tmp,a,b,x,y,w,h] = app.positions(app.resCh,lign,col);
                            
                            for i = 1 : lign
                                for j = 1 : col
                                    resCholesky = uieditfield(app.resCh,'numeric','HorizontalAlignment','center');
                                    resCholesky.Position = [ x y w h ];
                                    resCholesky.Value = ch(i,j);
                                    resCholesky.Editable = 'Off';
                                    resCholesky.Limits = [-1000 1000];
                                    resCholesky.BackgroundColor = [0.23 0.34 0.47];
                                    resCholesky.FontColor = [1 0.92 1];
                                    x = x + w + a ;
                                end
                                x = tmp ;
                                y = y - ( h + b );
                            end
                        end
                        symetrique = 'true';                      
                        
                    catch
                        warndlg('Oups !!... cette matrice n''est pas définie positive !','matrix not defined positive');
                    end                    
                else
                    errordlg('La matrice doivent etre carrée ','Erreur de dimension');
                end
            else
                errordlg('       Vous devez definir la matrice avant de commencer  !                                          Error : [ matrix not found ]','Erreur 404');
            end
        end

        % Callback function
        function btnProduitButtonPushed(app, event)
            
            exist1 = app.existe(app.premierMat);
            exist2 = app.existe(app.secondMat);
            if strcmp(exist1,'true') && strcmp(exist2,'true')
                [l1,c1] = size(app.mat1);
                [l2,c2] = size(app.mat2);
                m1 = app.getMatrice(app.mat1,l1,c1);
                m2 = app.getMatrice(app.mat2,l2,c2);
                if (c1 == l2)
                    produit = m1 * m2 ;                   
                    [tmp,a,b,x,y,w,h] = app.positions(app.resultProd,l1,c2);
                    for i = 1 : l1
                        for j = 1 : c2
                            prod(i,j) = uieditfield(app.resultProd,'numeric','HorizontalAlignment','center');
                            prod(i,j).Value = produit(i,j);
                            prod(i,j).Position = [ x y w h ];
                            prod(i,j).BackgroundColor = [0.55 0.651 0.72];
                            prod(i,j).FontColor = [0.98 1 1 ];
                            x = x + w + a ;
                        end
                        x = tmp ;
                        y = y - ( h + b );
                    end
                else
                    errordlg(' La dimension de ces deux matrice sont incompatible pour la multiplication','Error dimension');
                end
            else
                errordlg('       Vous devez definir la matrice avant de commencer  !                                          Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Callback function
        function btnQRButtonPushed(app, event)
            
            [ln,cl] = size(app.deco);           
            
            if ( app.decoSource.Children ~= 0 )
                app.ValQ.Visible ='on';
                app.ValR.Visible ='on';
                [q,r] = qr(app.getMatrice(app.deco,ln,cl));
                fprintf('\n ===================    la valeur de Q sur la décomposition de la matrice QR    ================\n\n');
                disp(q);
                fprintf(' ===================    la valeur de R sur la décomposition de la matrice QR    ================\n\n');
                disp(r);
                
                app.actualiser(app.ValQ);
                app.actualiser(app.ValR);
                [ql,qc] = size(q);
                
                [tmp,a,b,x,y,w,h] = app.positions(app.ValQ,ql,qc);                
                for i = 1 : ql
                    for j = 1 : qc
                        resQ = uieditfield(app.ValQ,'numeric','HorizontalAlignment','center');
                        resQ.Position = [ x y w h];
                        resQ.Value = q(i,j);
                        resQ.Editable = 'Off';
                        resQ.Limits = [-1000 1000];
                        resQ.BackgroundColor = [0.23 0.34 0.47];
                        resQ.FontColor = [0.1 0.99 0.17];
                        x = x + w + a ;
                    end
                    x = tmp ;
                    y = y - ( h + b );
                end                
                %   ==========================> Insertion de la matrice QR pour la valeur de R   ==========
                
                [rl,rc] = size(r);
                [tmp,a,b,x,y,w,h] = app.positions(app.ValR,rl,rc);
                for i = 1 : rl
                    for j = 1 : rc
                        resR = uieditfield(app.ValR,'numeric','HorizontalAlignment','center');
                        resR.Position = [ x y w h ];
                        resR.Value = r(i,j);
                        resR.Editable = 'Off';
                        resR.Limits = [-1000 1000];
                        resR.BackgroundColor = [0.23 0.34 0.47];
                        resR.FontColor = [0.1 1 1];
                        x = x + w + a ;
                    end
                    x = tmp ;
                    y = y - ( h + b );
                end
            else
                errordlg('       Vous devez definir la matrice avant de commencer  !                                       Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Callback function
        function btnSommeButtonPushed(app, event)
            
            exist1 = app.existe(app.premierMat);
            exist2 = app.existe(app.secondMat);
            if strcmp(exist1,'true') && strcmp(exist2,'true')
                [l1,c1] = size(app.mat1);
                [l2,c2] = size(app.mat2);
                m1 = app.getMatrice(app.mat1,l1,c1);
                m2 = app.getMatrice(app.mat2,l2,c2);
                if (l1 == l2) && (c1 == c2)                    
                    smm = m1 + m2 ;
                    [tmp,a,b,x,y,w,h] = app.positions(app.resultSom,l1,c1);
                    for i = 1 : l1
                        for j = 1 : c1
                            som(i,j) = uieditfield(app.resultSom,'numeric','HorizontalAlignment','center');
                            som(i,j).Value = smm(i,j);
                            som(i,j).Position = [ x y w h ];
                            som(i,j).BackgroundColor = [0.57 0.6 0.67];
                            som(i,j).FontColor = [0.98 1 1 ];
                            x = x + w + a ;
                        end
                        x = tmp ;
                        y = y - ( h + b );
                    end                    
                else
                    errordlg(' La dimension de ces deux matrice sont incompatible pour l''addition ','Error dimension');
                end
            else
                errordlg('       Vous devez definir la matrice avant de commencer  !                                          Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Button pushed function: closeSignal
        function closeSignalButtonPushed(app, event)
            close(app.fen);
        end

        % Callback function
        function decoNormButtonPushed(app, event)
            [ln,cl] = size(app.deco);
            
            app.actualiser(app.norme);
            if ( app.decoSource.Children ~= 0 )
                
                normDec = norm(app.getMatrice(app.deco,ln,cl));
                app.norme.Visible = 'on';
                fprintf('\n\n ===================    La matrice  A  ================\n\n');
                disp(app.getMatrice(app.deco,ln,cl));
                fprintf('\n\n ===================    La norme de la matrice  A  ================\n\n');
                disp(normDec);
                
                resNorm = uieditfield(app.norme,'numeric','HorizontalAlignment','center');
                resNorm.Position = [ 70 150 100 60 ];
                resNorm.BackgroundColor = [0.23 0.34 0.47];
                resNorm.FontColor = [1 .8 .0 ];
                resNorm.Value = normDec;
                resNorm.Editable = 'Off';
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Callback function
        function decoSvdButtonPushed(app, event)
            [ln,cl] = size(app.deco);
            if(app.decoSource.Children ~= 0)
                
                app.actualiser(app.singuliere);
                app.singuliere.Visible ='on';
                singlDec = svd(app.getMatrice(app.deco,ln,cl));
                fprintf(' ===================    la matrice singulière    ================\n\n');
                disp(singlDec);
                [l,c] = size(singlDec);
                
                [tmp,a,b,x,y,w,h] = app.positions(app.singuliere,l,c);                
                for i = 1 : l
                    for j = 1 : c                        
                        resSingl = uieditfield(app.singuliere,'numeric','HorizontalAlignment','center');
                        resSingl.Position = [ x y w h ];
                        resSingl.Value = singlDec(i,j);
                        resSingl.Editable = 'Off';
                        resSingl.BackgroundColor = [0.23 0.34 0.47];
                        resSingl.FontColor = [1 1 .8];
                        resSingl.Limits = [-1000 1000];
                        x = x + w + a ;
                    end
                    x = tmp ;
                    y = y - ( h + b );
                end
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end
        end

        % Callback function
        function detBtnButtonPushed(app, event)
            if (app.detSource.Children ~= 0 )
                [l,c] = size(app.transpos);
                if (l == c )
                    app.determinant.Visible = 'on';            
                    app.actualiser(app.determinant);
                    disp('============ La matrice A   ============');
                    disp(app.getMatrice(app.transpos,l,c));
                    fprintf(' \n\n ==================  Le determinantde A est :  ========================\n ');
                    res = det(app.getMatrice(app.transpos,l,c));
                    disp(res);
                    determinants = uieditfield(app.determinant,'numeric','HorizontalAlignment','center');
                    determinants.Position = [ 120 140 115 50 ];
                    determinants.Value = res;
                    determinants.BackgroundColor = [0.24 0.39 0.52];
                    determinants.FontColor = [1 1 .8];
                    determinants.Editable = 'Off';
                else
                    warndlg('Pour calculer le déterminant, il faut que la dimension de la matrice soit carrée !','Warning dimension');
                end
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Selection changed function: diracOptionBouton
        function diracOptionBoutonSelectionChanged(app, event)
            selectedButton = app.diracOptionBouton.SelectedObject;
            
            if  strcmp(app.foncDirac.Value,'')                
                errordlg('Vous devez definir la fonction !','Fonctionvides');            
            else
                T = app.periodeDirac.Value ;
                k = 1/T;
                t = -10 : k : 10 ;
                t0 = app.t0Dirac.Value ;
                a = lower(app.amplitudeDirac.Value) ;
                
                
                switch (selectedButton.Text)  
                    case 'Trace de dirac' 
                    try
                        y = eval(lower(app.foncDirac.Value));
                        f = app.diracs(y,t)
                        stem(app.axeDirac,t,f,'c');
                    catch
                        errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                    end
                    case 'Trace d''un rectangle'
                        try
                            k = k * 1/1000 ;
                            t = -1 : k : 1 ;
                            y = eval(lower(app.foncDirac.Value));
                            f = a*app.rectangle(y,t)
                            plot(app.axeDirac,t,f,'y');
                        catch
                            errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                        end
                    case 'Trace d''un triangle'
                        try
                            k = k * 1/1000 ;
                            t = -1 : k : 1 ;
                            y = eval(lower(app.foncDirac.Value));
                            f = a*tripuls(y)
                            plot(app.axeDirac,t,f,'g');
                        catch
                            errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                        end 
                    case 'Trace d''échelon'
                        try
                            t = -1 : 0.001 : 1 ;
                            y = eval(lower(app.foncDirac.Value));
                            f = a*heaviside(y)
                            plot(app.axeDirac,t,f,'m');
                        catch
                            errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                        end 
                 end   
            end              
        end

        % Callback function
        function eqOrdreBtnButtonPushed(app, event)
            col = app.eqCol.Value;
            lign = app.eqLign.Value;
            
            app.actualiser(app.eqResultValeur);
            app.actualiser(app.eqResultVariable);
            app.actualiser(app.eqNomVar);
            
            app.eqResultVariable.Visible = 'off';
            app.eqResultValeur.Visible = 'off';
            
            [tmp,a,b,x,y,w,h] = app.positions(app.eqNomVar,lign,col);
            
            for i = 1 : col
                for k =1 : 1
                    varName(i,k) = uieditfield(app.eqNomVar,'text','HorizontalAlignment','center');
                    varName(i,k).Position = [ x 20 w 40 ];
                    varName(i,k).BackgroundColor = [0.23 0.34 0.47];
                    varName(i,k).FontColor = [1 0.9 0.9];                    
                    x = x + w + a ;
                end
            end
            app.resVarName = varName;
            
            [tmp,a,b,x,y,w,h] = app.positions(app.eqSecond,lign,col);
            app.actualiser(app.eqSecond);
            for t = 1 : lign
                for l = 1 : 1
                    secd(t,l) = uieditfield(app.eqSecond,'numeric','HorizontalAlignment','center');
                    secd(t,l).Position = [ 30 y 110 h ];
                    secd(t,l).BackgroundColor = [0.23 0.34 0.47];
                    secd(t,l).FontColor = [1 0.85 0.95];
                end
                y = y - ( h + b );
            end
            app.seconde = secd;
            app.actualiser(app.eqSourceMat);
            app.equation = app.new_mat(app.eqSourceMat,[0.23 0.34 0.47],[0.9 1 0.98],lign,col);
        end

        % Callback function
        function eqResultBtnButtonPushed(app, event)
            
            if (app.eqSourceMat.Children ~= 0 )
                [ln,cl] = size(app.equation);
                resEq = app.getMatrice(app.equation,ln,cl);
                disp('   ===============  Le système d''équation linéaire   ============== ');
                disp(resEq);
                secd = app.getMatrice(app.seconde,ln,1);
                disp(secd)
                eqResult = resEq\secd;
                fprintf(' \n\n  ===============  Le système d''équation linéaire pour la second membre   ============== \n\n');
                disp(eqResult);
                [l,c] = size(app.resVarName);
                app.eqResultVariable.Visible = 'on';
                app.eqResultValeur.Visible = 'on';
                for i = 1 : l
                    if strcmp(app.resVarName(i,1).Value,'')
                        vide ='true';
                        break;
                    else                         
                        vide ='false';
                    end
                end
                if (strcmp(vide,'true'))
                    errordlg('Vous devez donner le nom des variables !','Nom du variable non complet');
                else
                    while strcmp(vide,'false')
                        for i = 1 : l
                            for k = 1 : l
                                if i ~= k
                                    if strcmp(lower(app.resVarName(i,1).Value),lower(app.resVarName(k,1).Value))
                                       doublant = 'true';
                                       break;
                                    else
                                       doublant = 'false';
                                    end
                                end
                            end
                            if strcmp(doublant,'true')
                                break;
                            else
                            end
                        end
                        if strcmp(doublant,'true')
                            errordlg('Le nom de la variable doivent etres différant , Veuillez le corrigée !','Doublant sur le nom du variable');
                        else
                            varN = app.getMatrice(app.resVarName,l,c);                            
                            app.actualiser(app.eqResultVariable);
                            
                            g = 100 - (5* cl)-30;
                            h = 180 - (180/2) - g ;
                            f = 300 + (5*cl);
                            for i = 1 : l
                                for j = 1 : c                                    
                                    afVar = uieditfield(app.eqResultVariable,'text','HorizontalAlignment','center');                                   
                                    afVar.Value = varN(i,j);                                
                                    afVar.Position = [ 50 f 200 g ];
                                    afVar.BackgroundColor = [0.23 0.34 0.47];
                                    afVar.FontColor = [1 .95 .9 ];
                                end
                                f = f - g - 10;
                            end
                            disp(varN);
                            app.actualiser(app.eqResultValeur);
                            g = 100 - (5* cl)-30;
                            h = 180 - (180/2) - g ;
                            f = 300 + (5*cl);
                            for i = 1 : l
                                for j = 1 : c
                                    afVal(i,j) = uieditfield(app.eqResultValeur,'numeric','HorizontalAlignment','center');
                                    afVal(i,j).Value = eqResult(i,j);
                                    afVal(i,j).Position = [ 70 f 200 g ];
                                    afVal(i,j).BackgroundColor = [0.23 0.34 0.47];
                                    afVal(i,j).FontColor = [0 1 1 ];
                                end
                                f = f - g - 10;
                            end
                        end                        
                        vide = 'true';
                    end
                end  
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end
        end

        % Callback function
        function fourierTransformebtnButtonPushed(app, event)
            
            domaine = app.infFourier.Value : app.pasFourier.Value : app.supFourier.Value ;               
            y  = lower(app.fourierFunction.Value);             
                          
             try
                 
                 title(app.axeOrigineFourier,strcat('Courbe de la fonction : ',y));
                 title(app.axeTransformer,strcat('Transformé de fourier de la fonction : ',y));
                 title(app.axeInversFourier,strcat('Inverse de la transformé de fourier du fonction : ',y));
               
                if ( app.dimensionSimple.Value == true )  
                    t = domaine;
                    g = eval(y);
                    f = fft(g);
                    
                    plot(app.axeOrigineFourier,t,g,'y');
                    plot(app.axeTransformer,t,f,'m');
                    plot(app.axeInversFourier,t,ifft(f),'c');
                    
                elseif ( app.deuxDimension.Value == true )
                    t = domaine;
                    g = eval(y);                    
                    fc = app.dim2(domaine,g)                    
                    f = fft2(fc);
                    plot(app.axeOrigineFourier,t,fc,'y');                    
                    plot(app.axeTransformer,t,f,'m');
                    plot(app.axeInversFourier,t,ifft2(f),'c');
                else
                    t = domaine;
                    g = eval(y);
                    
                    try
                        f = app.dim2(domaine,g); 
                        surfl(f);
                        k = fftn(g);
                        plot(app.axeOrigineFourier,g,'y');
                        plot(app.axeTransformer,domaine,k,'m');
                        plot(app.axeInversFourier,t,ifftn(k),'c');
                    catch
                        warndlg('Warning : [ function not supported ! ]','Warning fonction');
                    end
                    
                end
            catch
                errordlg('Fonction invalide !  Veuillez le verifier','Erreur fonction');
            end
        end

        % Callback function
        function invResultBtnButtonPushed(app, event)
            
            if app.invSourceMat.Children ~= 0
                app.actualiser(app.invResultMat);
                [ln,cl] = size(app.srcs);                
                resInv = app.getMatrice(app.srcs,ln, cl);
                disp(resInv);
                if det(resInv) == 0
                    warndlg(' Cette matrice n''est pas inversible ','Error');
                else                
                    fprintf(' \n ====================    Voicie la matrice carré      ====================  \n');
                    disp(resInv);
                    fprintf('  \n ==================    Et voicie son inverse     ====================== \n ');
                    invers = inv(resInv);
                    disp(invers);
                    
                    [tmp,a,b,x,y,w,h] = app.positions(app.invResultMat,ln,cl);
                    
                    for i = 1:ln
                        for j = 1:cl
                            invres(i,j) = uieditfield(app.invResultMat,'numeric','HorizontalAlignment','center');
                            invres(i,j).Position = [ x y  w h ];
                            invres(i,j).Value = invers(i,j);
                            invres(i,j).Editable = 'Off';
                            invres(i,j).Limits = [-1000 1000];
                            invres(i,j).BackgroundColor = [0.2 0.42 0.52];
                            invres(i,j).FontColor = [ 1 1 1 ];
                            x = x + w + a ;
                        end
                        x = tmp ;
                        y = y - ( h + b );
                    end
                end                
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Callback function
        function matBtnButtonPushed(app, event)
           app.accueil.Visible = 'off';
           app.tableauM.Visible = 'on';
           app.panAccueilMat.Visible = 'on';
        end

        % Button pushed function: matCloseBtn
        function matCloseBtnButtonPushed(app, event)
            close(app.fen);
        end

        % Callback function
        function menuCloseButtonPushed(app, event)
            if strcmp(app.activee,'')
                app.activee = app.panAccueilS ;
                h = app.activee.Position(4);
                k = app.tabSign.Position(3);
                w = k - 72 ; 
                app.activee.Position = [72 2 w h]; 
                app.panMenu.Position = [2 2 0 h];
                
                app.menuShow.Visible = 'on';
                app.menuClose.Visible = 'off';
            else
                h = app.activee.Position(4);
                k = app.tabSign.Position(3);
                w = k - 72 ; 
                app.activee.Position = [72 2 w h]; 
                app.panMenu.Position = [2 2 0 h];
                app.menuShow.Visible = 'on';
                app.menuClose.Visible = 'off';
            end            
        end

        % Callback function
        function menuShowButtonPushed(app, event)
            if strcmp(app.activee,'')
                app.activee = app.panAccueilS ;
                h = app.activee.Position(4);
                app.panMenu.Visible = 'on';
                app.panMenu.Position = [2 2 330 h];
                
                app.menuShow.Visible = 'off';
                app.menuClose.Visible = 'on';
                app.activee.Position = [333 2 1065 h]; 
            else
                h = app.activee.Position(4);
                app.panMenu.Visible = 'on';
                app.panMenu.Position = [2 2 330 h];
                
                app.menuShow.Visible = 'off';
                app.menuClose.Visible = 'on';
                app.activee.Position = [333 2 1065 h]; 
            end     
        end

        % Callback function
        function ordreDecoButtonPushed(app, event)
            cl = app.decoCol.Value;
            ln = app.decoLign.Value;
            app.singuliere.Visible ='off';
            app.norme.Visible = 'off';
            %        if ln == cl
            
            app.actualiser(app.singuliere);
            app.actualiser(app.norme);
            app.actualiser(app.decoSource);
            app.actualiser(app.resCh);
            app.ValQ.Visible ='off';
            app.ValR.Visible ='off';
            app.deco = app.new_mat(app.decoSource,[0.23 0.34 0.47],[0.95 0.998 0.98],ln,cl);            
            
        end

        % Button pushed function: principal_Aide
        function principal_AideButtonPushed(app, event)
            
            aideLogiciel = 'Vous avez une option pour aller dans la partie de traitement de signal en cliquant le bouton [ Aller au Signal ] ou sur le bouton [ Aller au Mathématique ] pour aller dans la partie Mathématique\n\n Cliquer sur Quitter pour fermer l''application';
            helpdlg(aideLogiciel,'Utilisation de l''application');
        end

        % Callback function: Button_2, apropos, aproposMath, 
        % principal_Apropos
        function principal_AproposButtonPushed(app, event)
            l7 = '--------------------------------------------------------------------------';
            l1 = ' ------------    Utilitaire pour image en M1    -------------';
            l2 = '   ______________________________________________';
            l3 = '             Créateur :  Monome 10  Genie Informatique       ';
            l4 = '             Date de créatiion : février 2021';
            l5 = '             Version  : 1.0.3';
            l6 = '             ________________';
            aide = sprintf('\n\t %s\n\t %s\n\t%s\n\n\t%s \n\t%s\n\n\t%s\n\t%s',l7,l1,l2,l3, l4,l5,l6);
            helpdlg(aide,'A propos du logiciel ');
        end

        % Button pushed function: quitPrincipalBtn
        function quitPrincipalBtnButtonPushed(app, event)
            close(app.fen);
        end

        % Callback function
        function rectangleSignalPushed(app, event)
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.rectangleSignal,app.allbouton,6);
            app.activee = app.panelActiver(app.panConvolution,app.allpanel,7);
        end

        % Button pushed function: retournAccueilMath
        function retournAccueilMathButtonPushed(app, event)
            app.accueil.Visible = 'on';
            app.tableauM.Visible = 'off';
        end

        % Button pushed function: signBoutonValider
        function signBoutonValiderButtonPushed(app, event)
            t = -1 : 0.001 : 1 ;
            y = lower(app.fonctionSign.Value);
            g = eval(y); 
            f = sign(g);
            title(app.axeSign,strcat('Sign de signal : ',y));            
            plot(app.axeSign,t,f,'c');
        end

        % Value changed function: signOption
        function signOptionValueChanged(app, event)
            value = app.signOption.Value;
            t = -1 : 0.001 : 1 ;
            switch value
                case 'sin(t)'
                    app.fonctionSign.Value = 'sin(t)';
                    y = lower(app.fonctionSign.Value);        
                    g = eval(y); 
                    f = sign(g);
                    title(app.axeSign,strcat('Sign de signal : ',y));          
                    plot(app.axeSign,t,f,'c');
                case 'tan(t)'
                    app.fonctionSign.Value = 'tan(t)';
                    y = lower(app.fonctionSign.Value);        
                    g = eval(y);   
                    f = sign(g);
                    title(app.axeSign,strcat('Sign de signal : ',y));            
                    plot(app.axeSign,t,f,'c');
                case 'sinc(t)'
                    app.fonctionSign.Value = 'sinc(t)';
                    y = lower(app.fonctionSign.Value);        
                    g = eval(y); 
                    f = sign(g);
                    title(app.axeSign,strcat('Sign de signal : ',y)); 
                    plot(app.axeSign,t,f,'c');
                
            end
        end

        % Button pushed function: signRetourneAccueil
        function signRetourneAccueilButtonPushed(app, event)
            app.accueil.Visible = 'on';
            app.tableauM.Visible = 'off';
        end

        % Callback function
        function tansBtnButtonPushed(app, event)
            if ( app.detSource.Children ~= 0 )
                [l,c] = size(app.transpos);
                app.actualiser(app.transposee);
                res = transpose(app.getMatrice(app.transpos,l,c));                
                [ln,cl] = size(res);
                fprintf(' ==================  La matrice A  ======== \n\n');
                disp(app.getMatrice(app.transpos,l,c));
                fprintf(' ==================  transposition du matrice A  ======== \n\n');
                disp(res);
                
                [tmp,a,b,x,y,w,h] = app.positions(app.transposee,ln,cl);                
                for i = 1:ln
                    for j = 1:cl
                        transp(i,j) = uieditfield(app.transposee,'numeric','HorizontalAlignment','center');
                        transp(i,j).Position = [ x y w h ];
                        transp(i,j).Value = res(i,j);
                        transp(i,j).Editable = 'Off';
                        transp(i,j).Limits = [-1000 1000];
                        transp(i,j).BackgroundColor = [0.24 0.39 0.52];
                        transp(i,j).FontColor = [1 1 0.9];
                        x = x + w + a ;
                    end
                    x = tmp ;
                    y = y - ( h + b );
                end
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end           
        end

        % Button pushed function: tracageEchelon
        function tracageEchelonButtonPushed(app, event)
            app.labTitre.Text = 'Traçage de sign d''un signal';
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.tracageEchelon,app.allbouton,6);
            app.activee = app.panelActiver(app.panSign,app.allpanel,7);
        end

        % Callback function
        function tracagedunsignalrampPushed(app, event)
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.tracagedunsignalramp,app.allbouton,6);
            app.activee = app.panelActiver(app.panRamp,app.allpanel,7);
        end

        % Callback function
        function traceBtnButtonPushed(app, event)
            
            [l,c] = size(app.transpos);
            if (app.detSource.Children ~= 0)
                if (l == c )
                    app.traces.Visible = 'on';
                    app.actualiser(app.traces);                    
                    res = trace(app.getMatrice(app.transpos,l,c));
                    fprintf('============= Trace de la Matrice   =========== ');
                    disp(res);
                    trac = uieditfield(app.traces,'numeric','HorizontalAlignment','center');
                    trac.Position = [ 120 140 115 50 ];
                    trac.Value = res;
                    trac.BackgroundColor = [0.24 0.39 0.52];
                    trac.FontColor = [1 1 0.9];
                    trac.Editable = 'Off';
                else
                    warndlg('Pour calculer la trace, il faut que la dimension de la matrice soit carrée !','Warning dimension');
                end                
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end            
        end

        % Button pushed function: traceConvolution
        function traceConvolutionButtonPushed(app, event)
            app.labTitre.Text = 'Traçage de convolution d''un signal';
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.boutonActiver(app.traceConvolution,app.allbouton,6);
            app.activee = app.panelActiver(app.panConvolution,app.allpanel,7);
        end

        % Button pushed function: traceSignaBruit
        function traceSignaBruitButtonPushed(app, event)
            try
                k = app.periodeB.Value ;
                t0 = app.t0B.Value ;
                t = 0 : 1/k : 10 ;
                signale = lower(app.foncSignalBruit.Value);
                bruit = lower(app.foncBruit.Value);
                a = app.amplitudeB.Value ;
                g = a.*eval(signale) + rand(1,length(t));
                b = eval(bruit) + rand(1,length(t));
                y = b+g + rand(1,length(t));
                plot(app.axeSignalAvecBruit,t,g,'g',t,b,'m');
                plot(app.axeResBruit,t,y,'c');
            catch
                errordlg('Fonction invalide ! \n Veuillez le verifier','Erreur fonction');
            end
            
        end

        % Callback function
        function traceSignalSimpleButtonPushed(app, event)
            
            t = [ app.bornInf.Value : app.pasInterval.Value : app.bornSup.Value ];            
            titres = strcat("Courbe de la fonction : ",lower(app.simpleFonction.Value));
            title(app.axeSimple,titres);
            try
                g = eval(lower(app.simpleFonction.Value));
                plot(app.axeSimple,t,g);
            catch 
                errordlg('Fonction invalide ! \n Veuillez le verifier','Erreur fonction');
            end
        end

        % Button pushed function: tracerConvolution
        function tracerConvolutionButtonPushed(app, event)
            
            T = 1/app.freqConv.Value ;
            t = app.infConv.Value : T : app.supConv.Value ;
            if strcmp(app.foncConv1.Value,'') || strcmp(app.foncConv2.Value,'')
                errordlg('ous devez completer le deux champs !','Erreur [ champs vide ]');
            else
                try
                    s1 = eval(lower(app.foncConv1.Value));
                    s2 = eval(lower(app.foncConv2.Value));
                    f = s1.*s2; 
                    z = conv(s1,s2);
                    plot(app.axeConvolution,z,'c');
                catch
                    errordlg('Fonction invalide ! \n Veuillez le verifier','Erreur fonction');                
                end
            end
        end

        % Button pushed function: tracerDirac
        function tracerDiracButtonPushed(app, event)
            selectedButton = app.diracOptionBouton.SelectedObject;
            
            if  strcmp(app.foncDirac.Value,'')                
                errordlg('Vous devez definir la fonction !','Fonctionvides');            
            else
                k = 1/app.periodeDirac.Value ;
                t = -10 : k : 10 ;
                t0 = app.t0Dirac.Value ;
                a = app.amplitudeDirac.Value ;
                
                switch (selectedButton.Text)  
                    case 'Trace de dirac' 
                    try
                        y = eval(lower(app.foncDirac.Value));
                        f = app.diracs(y,t);
                        stem(app.axeDirac,t,f,'c');
                    catch
                        errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                    end
                    case 'Trace d''un rectangle'
                        try
                            k = k * 1/1000 ;
                            t = -1 : k : 1 ;
                            y = eval(lower(app.foncDirac.Value));
                            f = a*app.rectangle(y,t);
                            plot(app.axeDirac,t,f,'y');
                        catch
                            errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                        end
                    case 'Trace d''un triangle'
                        try
                            k = k * 1/1000 ;
                            t = -1 : k : 1 ;
                            y = eval(lower(app.foncDirac.Value));
                            f = a*tripuls(y)
                            plot(app.axeDirac,t,f,'g');
                        catch
                            errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                        end 
                    case 'Trace d''échelon'
                        try
                            t = -1 : 0.001 : 1 ;
                            y = eval(lower(app.foncDirac.Value));
                            f = a*heaviside(y)
                            plot(app.axeDirac,t,f,'m');
                        catch
                            errordlg('Votre fonction invalide !, Veuillez la verifier !','Invalide fonction');
                        end
                 end   
            end 
        end

        % Callback function
        function transformationEnFourierSimplePushed(app, event)
            app.labTitre.Text = 'Traçage d''une fonction et son Trasnformé en Fourier';
            app.allbouton = [app.traceFonctionSimple,app.transformerFourier,app.traceConvolution,app.TracagedunBruit,app.Tracagedunsignaldedirac,app.tracageEchelon];            
            app.allpanel = [app.panSimpleFunction,app.transformFourier,app.signalBruit,app.signalDirac,app.panConvolution,app.panAccueilS,app.panSign];
            app.activee = app.panelActiver(app.transformFourier,app.allpanel,7);
            app.boutonActiver(app.transformerFourier,app.allbouton,6);
        end

        % Callback function
        function typeOperationValueChanged(app, event)
            
            value = app.typeOperation.Value;
            switch value
                case 'Choisissez ici votre type d''Operation matricielle'
                    app.vectP.Visible = 'off';
                    app.valP.Visible = 'off';
                    app.sommProduitmat.Visible='off';
                    app.trace_det_trans.Visible='off';
                    app.decoMat.Visible = 'off';
                    app.eqLineaire.Visible ='off';
                    app.invMat.Visible = 'off';
                    app.vect_val_Mat.Visible = 'off';
                    app.panAccueilMat.Visible = 'on';
                    
                case 'Calcul vecteur et valeur propre'
                    app.vectP.Visible = 'off';
                    app.valP.Visible = 'off';
                    app.sommProduitmat.Visible='off';
                    app.trace_det_trans.Visible='off';
                    app.decoMat.Visible = 'off';
                    app.eqLineaire.Visible ='off';
                    app.invMat.Visible = 'off';
                    app.vect_val_Mat.Visible = 'on';
                    app.panAccueilMat.Visible = 'off';
                    
                case 'Systeme d''équation linéaire'
                    app.panAccueilMat.Visible = 'off';
                    app.sommProduitmat.Visible='off';
                    app.trace_det_trans.Visible='off';
                    app.decoMat.Visible = 'off';
                    app.vect_val_Mat.Visible = 'off';
                    app.eqLineaire.Visible ='on';
                    app.invMat.Visible = 'off';
                    
                case 'Decomposition d''une matrice'
                    
                    app.singuliere.Visible ='off';
                    app.norme.Visible = 'off';
                    app.panAccueilMat.Visible = 'off';
                    app.sommProduitmat.Visible='off';
                    app.trace_det_trans.Visible='off';
                    app.decoMat.Visible = 'on';
                    app.vect_val_Mat.Visible = 'off';
                    app.eqLineaire.Visible = 'off';
                    app.invMat.Visible = 'off';
                    
                case 'Caclul inverse d''une matrice'
                    app.panAccueilMat.Visible = 'off';
                    app.sommProduitmat.Visible='off';
                    app.trace_det_trans.Visible='off';
                    app.decoMat.Visible = 'off';
                    app.vect_val_Mat.Visible = 'off';
                    app.eqLineaire.Visible = 'off';
                    app.invMat.Visible = 'on';
                    
                case 'Somme et Produit de deux matrice'
                    app.sommProduitmat.Visible='on';
                    app.trace_det_trans.Visible='off';
                    app.decoMat.Visible = 'off';
                    app.vect_val_Mat.Visible = 'off';
                    app.eqLineaire.Visible = 'off';
                    app.invMat.Visible = 'off';
                    app.panAccueilMat.Visible = 'off';
                case 'Transposition , determinant et trace d''une matrice'
                    app.sommProduitmat.Visible='off';
                    app.trace_det_trans.Visible='on';
                    app.decoMat.Visible = 'off';
                    app.vect_val_Mat.Visible = 'off';
                    app.eqLineaire.Visible = 'off';
                    app.invMat.Visible = 'off';
                    app.panAccueilMat.Visible = 'off';
            end
        end

        % Callback function
        function validMat1ButtonPushed(app, event)
            l1 =  app.lign_mat1.Value;
            col1 = app.col_mat1.Value;
            
            app.actualiser(app.premierMat);
            app.actualiser(app.resultSom)
            app.actualiser(app.resultProd);
            app.mat1 = app.new_mat(app.premierMat,[0.57 0.6 0.67],[0.98 1 1 ],l1,col1);
            
            
        end

        % Callback function
        function validMat2ButtonPushed(app, event)
            col2 = app.col_mat2.Value;
            l2 =  app.lign_mat2.Value;
            
            app.actualiser(app.secondMat);
            app.actualiser(app.resultSom)
            app.actualiser(app.resultProd);
            app.mat2 = app.new_mat(app.secondMat,[0.45 0.51 0.62],[0.98 1 1 ],l2,col2);
        end

        % Callback function
        function validTransButtonPushed(app, event)
            
            l = app.detLign.Value;
            c = app.detCol.Value;
            
            app.actualiser(app.detSource);
            app.actualiser(app.determinant);
            app.actualiser(app.traces);
            app.actualiser(app.transposee);
            app.determinant.Visible = 'off';
            app.traces.Visible = 'off';
            
            app.transpos = app.new_mat(app.detSource,[0.24 0.39 0.52],[1 1 0.9],l,c);
        end

        % Callback function
        function vectOrdreBtnButtonPushed(app, event)
            cl = app.vectCol.Value;
            ln = app.vectLign.Value;
            app.vectP.Visible ='off';
            app.valP.Visible = 'off';
            if ln == cl
                app.actualiser(app.vectSourceMat);
                app.actualiser(app.vectP);
                app.actualiser(app.valP);
                app.vector = app.new_mat(app.vectSourceMat,[0.1 0.42 0.53 ],[0.9 0.9 1],ln,cl);
            else
                warndlg('Le nombre de ligne et nombre de colonne doivent etres égale','Warning');
            end
        end

        % Callback function
        function vectResultBtnButtonPushed(app, event)
            if (app.vectSourceMat.Children ~= 0 )
                app.vectP.Visible ='on';
                app.valP.Visible = 'on';
                
                app.actualiser(app.vectP);
                app.actualiser(app.valP);
                [ln,cl] = size(app.vector);
                
                vectMat = app.getMatrice(app.vector,ln, cl);
                [vecteurP,valeurP] = eig(vectMat);
                
                fprintf(' \n\n ====================    Vecteur propre      ====================  \n');
                disp(vecteurP);
                fprintf('  \n\n ==================    Valeur propre     ====================== \n ');
                disp(valeurP);
                
                [tmp,a,b,x,y,w,h] = app.positions(app.vectP,ln,cl);
                                
                for i = 1:ln
                    for j = 1:cl
                        vecteure = uieditfield(app.vectP,'numeric','HorizontalAlignment','center');
                        vecteure.Position = [ x y w h ];
                        vecteure.Value = vecteurP(i,j);
                        vecteure.Editable = 'Off';
                        vecteure.Limits = [-1000 1000];
                        vecteure.BackgroundColor = [ 0.1 0.42 0.53];
                        vecteure.FontColor = [0 1 1];
                        x = x + w + a ;
                    end
                    x = tmp ;
                    y = y - ( h + b );
                end
                
                [tmps,aa,bb,xx,yy,ww,hh] = app.positions(app.valP,ln,cl);
                
                for i = 1:ln
                    for j = 1:cl
                        Element = uieditfield(app.valP,'numeric','HorizontalAlignment','center');
                        Element.Position = [xx yy ww hh ];
                        Element.Value = valeurP(i,j);
                        Element.Editable = 'Off';
                        Element.Limits = [-1000 1000];
                        Element.BackgroundColor = [ 0.1 0.42 0.53];
                        Element.FontColor = [0 1 0];
                        xx = xx + ww + aa ;
                    end
                    xx = tmps ;
                    yy = yy - ( hh + bb );
                end
            else
                errordlg(' Vous devez definir la matrice avant de commencer!  Error : [ matrix not found ]','Erreur 404');
            end             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fen = uifigure;
            app.fen.Name = 'Utilitaire pour image';
            app.tableauM = uitabgroup(app.fen);
            app.tableauM.Visible = 'off';
            app.tabMath = uitab(app.tableauM);
            app.tabMath.Title = 'PARTIE MATHEMATIQUES';
            app.TypedoperationsMat = uilabel(app.tabMath);
            app.TypedoperationsMat.Text = 'Type des opérations';
            app.typeOperation = uidropdown(app.tabMath);
            app.typeOperation.Items = {'Choisissez ici votre type d''Operation matricielle', 'Calcul vecteur et valeur propre', 'Systeme d''équation linéaire', 'Decomposition d''une matrice', 'Caclul inverse d''une matrice', 'Somme et Produit de deux matrice', 'Transposition , determinant et trace d''une matrice'};
            app.typeOperation.ValueChangedFcn = createCallbackFcn(app, @typeOperationValueChanged, true);
            app.typeOperation.Value = 'Choisissez ici votre type d''Operation matricielle';
            app.trace_det_trans = uipanel(app.tabMath);
            app.trace_det_trans.Title = 'Calcul déterminant, trace et le transposé d''une  matrice';
            app.trace_det_trans.Visible = 'off';
            app.resultat_trace_det_tranc = uipanel(app.trace_det_trans);
            app.resultat_trace_det_tranc.Title = 'Résultat';
            app.determinant = uipanel(app.resultat_trace_det_tranc);
            app.determinant.Title = 'Déterminant de A';
            app.determinant.Visible = 'off';
            app.traces = uipanel(app.resultat_trace_det_tranc);
            app.traces.Title = 'Trace de A';
            app.traces.Visible = 'off';
            app.tansBtn = uibutton(app.trace_det_trans, 'push');
            app.tansBtn.ButtonPushedFcn = createCallbackFcn(app, @tansBtnButtonPushed, true);
            app.tansBtn.Text = 'Transposé';
            app.detBtn = uibutton(app.trace_det_trans, 'push');
            app.detBtn.ButtonPushedFcn = createCallbackFcn(app, @detBtnButtonPushed, true);
            app.detBtn.Text = 'Déterminant';
            app.transposee = uipanel(app.trace_det_trans);
            app.transposee.Title = 'Transposé de A';
            app.traceBtn = uibutton(app.trace_det_trans, 'push');
            app.traceBtn.ButtonPushedFcn = createCallbackFcn(app, @traceBtnButtonPushed, true);
            app.traceBtn.Text = 'Trace';
            app.detSource = uipanel(app.trace_det_trans);
            app.detSource.Title = 'Matrice A';
            app.determinant_2 = uipanel(app.trace_det_trans);
            app.determinant_2.Title = 'Dimension du matrice';
            app.NombredecolonneLabel_2 = uilabel(app.determinant_2);
            app.NombredecolonneLabel_2.Text = 'Nombre de colonne';
            app.detCol = uispinner(app.determinant_2);
            app.detCol.Limits = [1 1000];
            app.detCol.RoundFractionalValues = 'on';
            app.detCol.ValueDisplayFormat = '%.0f';
            app.detCol.Editable = 'off';
            app.detCol.Value = 1;
            app.NombredeligneLabel = uilabel(app.determinant_2);
            app.NombredeligneLabel.Text = 'Nombre de ligne ';
            app.detLign = uispinner(app.determinant_2);
            app.detLign.Limits = [1 1000];
            app.detLign.RoundFractionalValues = 'on';
            app.detLign.ValueDisplayFormat = '%.0f';
            app.detLign.Editable = 'off';
            app.detLign.Value = 1;
            app.validTrans = uibutton(app.determinant_2, 'push');
            app.validTrans.ButtonPushedFcn = createCallbackFcn(app, @validTransButtonPushed, true);
            app.validTrans.Text = 'Valider';
            app.sommProduitmat = uipanel(app.tabMath);
            app.sommProduitmat.Title = 'Calcul somme et produits de deux  matrice';
            app.sommProduitmat.Visible = 'off';
            app.premierMat = uipanel(app.sommProduitmat);
            app.premierMat.Title = 'Première matrice';
            app.secondMat = uipanel(app.sommProduitmat);
            app.secondMat.Title = 'Second matrice';
            app.btnProduit = uibutton(app.sommProduitmat, 'push');
            app.btnProduit.ButtonPushedFcn = createCallbackFcn(app, @btnProduitButtonPushed, true);
            app.btnProduit.Text = 'Produits';
            app.btnSomme = uibutton(app.sommProduitmat, 'push');
            app.btnSomme.ButtonPushedFcn = createCallbackFcn(app, @btnSommeButtonPushed, true);
            app.btnSomme.Text = 'Somme';
            app.resultProd = uipanel(app.sommProduitmat);
            app.resultProd.Title = 'Résultat de la produit de ces deux matrice';
            app.resultSom = uipanel(app.sommProduitmat);
            app.resultSom.Title = 'Résultat de la somme de ces deux matrice';
            app.DimensionPremirematricePanel = uipanel(app.sommProduitmat);
            app.DimensionPremirematricePanel.Title = 'Dimension Première matrice';
            app.LigneLabel = uilabel(app.DimensionPremirematricePanel);
            app.LigneLabel.Text = 'Ligne ';
            app.lign_mat1 = uispinner(app.DimensionPremirematricePanel);
            app.lign_mat1.Limits = [1 1000];
            app.lign_mat1.RoundFractionalValues = 'on';
            app.lign_mat1.ValueDisplayFormat = '%.0f';
            app.lign_mat1.Value = 1;
            app.ColonneLabel = uilabel(app.DimensionPremirematricePanel);
            app.ColonneLabel.Text = 'Colonne';
            app.col_mat1 = uispinner(app.DimensionPremirematricePanel);
            app.col_mat1.Limits = [1 1000];
            app.col_mat1.RoundFractionalValues = 'on';
            app.col_mat1.ValueDisplayFormat = '%.0f';
            app.col_mat1.Value = 1;
            app.validMat1 = uibutton(app.DimensionPremirematricePanel, 'push');
            app.validMat1.ButtonPushedFcn = createCallbackFcn(app, @validMat1ButtonPushed, true);
            app.validMat1.Text = 'Valider';
            app.DimensionsecondMatricePanel = uipanel(app.sommProduitmat);
            app.DimensionsecondMatricePanel.Title = 'Dimension second Matrice';
            app.LigneLabel_2 = uilabel(app.DimensionsecondMatricePanel);
            app.LigneLabel_2.Text = 'Ligne ';
            app.lign_mat2 = uispinner(app.DimensionsecondMatricePanel);
            app.lign_mat2.Limits = [1 1000];
            app.lign_mat2.RoundFractionalValues = 'on';
            app.lign_mat2.ValueDisplayFormat = '%.0f';
            app.lign_mat2.Value = 1;
            app.ColonneLabel_2 = uilabel(app.DimensionsecondMatricePanel);
            app.ColonneLabel_2.Text = 'Colonne';
            app.col_mat2 = uispinner(app.DimensionsecondMatricePanel);
            app.col_mat2.Limits = [1 1000];
            app.col_mat2.RoundFractionalValues = 'on';
            app.col_mat2.ValueDisplayFormat = '%.0f';
            app.col_mat2.Value = 1;
            app.validMat2 = uibutton(app.DimensionsecondMatricePanel, 'push');
            app.validMat2.ButtonPushedFcn = createCallbackFcn(app, @validMat2ButtonPushed, true);
            app.validMat2.Text = 'Valider';
            app.decoMat = uipanel(app.tabMath);
            app.decoMat.Title = 'Décomposition d''une matrice';
            app.decoMat.Visible = 'off';
            app.decoSource = uipanel(app.decoMat);
            app.decoSource.Title = 'Matrice ';
            app.NombredeligneSpinner_4Label_4 = uilabel(app.decoMat);
            app.NombredeligneSpinner_4Label_4.Text = 'Nombre de  ligne ';
            app.decoLign = uispinner(app.decoMat);
            app.decoLign.Limits = [1 1000];
            app.decoLign.ValueDisplayFormat = '%.0f';
            app.decoLign.Value = 1;
            app.NombredecolonneSpinner_4Label_4 = uilabel(app.decoMat);
            app.NombredecolonneSpinner_4Label_4.Text = 'Nombre de  colonne';
            app.decoCol = uispinner(app.decoMat);
            app.decoCol.Limits = [1 1000];
            app.decoCol.ValueDisplayFormat = '%.0f';
            app.decoCol.Value = 1;
            app.ordreDeco = uibutton(app.decoMat, 'push');
            app.ordreDeco.ButtonPushedFcn = createCallbackFcn(app, @ordreDecoButtonPushed, true);
            app.ordreDeco.Text = 'Valider';
            app.decoSvd = uibutton(app.decoMat, 'push');
            app.decoSvd.ButtonPushedFcn = createCallbackFcn(app, @decoSvdButtonPushed, true);
            app.decoSvd.Text = 'Singulière du matrice';
            app.decoNorm = uibutton(app.decoMat, 'push');
            app.decoNorm.ButtonPushedFcn = createCallbackFcn(app, @decoNormButtonPushed, true);
            app.decoNorm.Text = 'Norme du matrice';
            app.decoResult = uipanel(app.decoMat);
            app.decoResult.Title = 'Résultat';
            app.norme = uipanel(app.decoResult);
            app.norme.Title = 'Norme de la matrice';
            app.norme.Visible = 'off';
            app.singuliere = uipanel(app.decoResult);
            app.singuliere.Title = 'Valeur singulière de la matrice';
            app.singuliere.Visible = 'off';
            app.btnCh = uibutton(app.decoMat, 'push');
            app.btnCh.ButtonPushedFcn = createCallbackFcn(app, @btnChButtonPushed, true);
            app.btnCh.Text = 'Résultat Cholesky';
            app.btnQR = uibutton(app.decoMat, 'push');
            app.btnQR.ButtonPushedFcn = createCallbackFcn(app, @btnQRButtonPushed, true);
            app.btnQR.Text = 'Résultat QR';
            app.resCh = uipanel(app.decoMat);
            app.resCh.Title = 'Résultat décomposition Cholesky';
            app.resQR = uipanel(app.decoMat);
            app.resQR.Title = 'Résultat décomposition QR';
            app.ValQ = uipanel(app.resQR);
            app.ValQ.Title = 'Valeur de Q';
            app.ValQ.Visible = 'off';
            app.ValR = uipanel(app.resQR);
            app.ValR.Title = 'Valeur de R';
            app.ValR.Visible = 'off';
            app.eqLineaire = uipanel(app.tabMath);
            app.eqLineaire.Title = 'Equation linéaire d''une matrice';
            app.eqLineaire.Visible = 'off';
            app.eqSourceMat = uipanel(app.eqLineaire);
            app.eqSourceMat.Title = 'Système d''une équation sous forme matricielle';
            app.eqSecond = uipanel(app.eqLineaire);
            app.eqSecond.Title = 'Second membre';
            app.eqResultBtn = uibutton(app.eqLineaire, 'push');
            app.eqResultBtn.ButtonPushedFcn = createCallbackFcn(app, @eqResultBtnButtonPushed, true);
            app.eqResultBtn.Text = 'Voir le résultat';
            app.eqOrdreBtn = uibutton(app.eqLineaire, 'push');
            app.eqOrdreBtn.ButtonPushedFcn = createCallbackFcn(app, @eqOrdreBtnButtonPushed, true);
            app.eqOrdreBtn.Text = 'Valider';
            app.eqResultat = uipanel(app.eqLineaire);
            app.eqResultat.Title = 'Resultat';
            app.eqResultVariable = uipanel(app.eqResultat);
            app.eqResultVariable.Title = 'Variable';
            app.eqResultVariable.Visible = 'off';
            app.eqResultValeur = uipanel(app.eqResultat);
            app.eqResultValeur.Title = 'Valeur';
            app.eqResultValeur.Visible = 'off';
            app.eqNomVar = uipanel(app.eqLineaire);
            app.eqNomVar.Title = 'Nom des variables';
            app.vectSourceMat_3 = uipanel(app.eqLineaire);
            app.NombredesquationsLabel = uilabel(app.vectSourceMat_3);
            app.NombredesquationsLabel.Text = 'Nombre des équations ';
            app.eqLign = uispinner(app.vectSourceMat_3);
            app.eqLign.Limits = [1 1000];
            app.eqLign.ValueDisplayFormat = '%.0f';
            app.eqLign.Value = 1;
            app.NombredesinconnuesLabel = uilabel(app.vectSourceMat_3);
            app.NombredesinconnuesLabel.Text = 'Nombre des inconnues';
            app.eqCol = uispinner(app.vectSourceMat_3);
            app.eqCol.Limits = [1 1000];
            app.eqCol.ValueDisplayFormat = '%.0f';
            app.eqCol.Value = 1;
            app.vect_val_Mat = uipanel(app.tabMath);
            app.vect_val_Mat.Title = 'Calcul vecteur propre et valeur propre d''une matrice';
            app.vect_val_Mat.Visible = 'off';
            app.vectSourceMat = uipanel(app.vect_val_Mat);
            app.vectSourceMat.Title = 'Matrice carré ';
            app.vectResultat = uipanel(app.vect_val_Mat);
            app.vectResultat.Title = 'Résultat';
            app.vectP = uipanel(app.vectResultat);
            app.vectP.Title = 'Vecteur propre';
            app.valP = uipanel(app.vectResultat);
            app.valP.Title = 'Valeur propre';
            app.vectResultBtn = uibutton(app.vect_val_Mat, 'push');
            app.vectResultBtn.ButtonPushedFcn = createCallbackFcn(app, @vectResultBtnButtonPushed, true);
            app.vectResultBtn.Text = 'Resultat';
            app.vectOrdreBtn = uibutton(app.vect_val_Mat, 'push');
            app.vectOrdreBtn.ButtonPushedFcn = createCallbackFcn(app, @vectOrdreBtnButtonPushed, true);
            app.vectOrdreBtn.Text = 'Valider';
            app.dimVectP = uipanel(app.vect_val_Mat);
            app.dimVectP.Title = 'Dimension de la Matrice';
            app.NombredecolonneSpinner_4Label_5 = uilabel(app.dimVectP);
            app.NombredecolonneSpinner_4Label_5.Text = 'Nombre de  colonne';
            app.vectCol = uispinner(app.dimVectP);
            app.vectCol.Limits = [1 1000];
            app.vectCol.ValueDisplayFormat = '%.0f';
            app.vectCol.Value = 1;
            app.NombredeligneSpinner_4Label_5 = uilabel(app.dimVectP);
            app.NombredeligneSpinner_4Label_5.Text = 'Nombre de  ligne ';
            app.vectLign = uispinner(app.dimVectP);
            app.vectLign.Limits = [1 1000];
            app.vectLign.ValueDisplayFormat = '%.0f';
            app.vectLign.Value = 1;
            app.invMat = uipanel(app.tabMath);
            app.invMat.Title = 'Calcul inverse d''une matrice';
            app.invMat.Visible = 'off';
            app.invSourceMat = uipanel(app.invMat);
            app.invSourceMat.Title = 'Matrice carré ';
            app.invResultMat = uipanel(app.invMat);
            app.invResultMat.Title = 'Inverse du matrice';
            app.invResultBtn = uibutton(app.invMat, 'push');
            app.invResultBtn.ButtonPushedFcn = createCallbackFcn(app, @invResultBtnButtonPushed, true);
            app.invResultBtn.Text = 'Inverser';
            app.ValiderButton = uibutton(app.invMat, 'push');
            app.ValiderButton.ButtonPushedFcn = createCallbackFcn(app, @ValiderButtonPushed, true);
            app.ValiderButton.Text = 'Valider';
            app.DimensiondelaMatricePanel = uipanel(app.invMat);
            app.DimensiondelaMatricePanel.Title = 'Dimension de la Matrice';
            app.NombredeligneSpinnerLabel = uilabel(app.DimensiondelaMatricePanel);
            app.NombredeligneSpinnerLabel.Text = 'Nombre de  ligne ';
            app.invLign = uispinner(app.DimensiondelaMatricePanel);
            app.invLign.Limits = [1 1000];
            app.invLign.RoundFractionalValues = 'on';
            app.invLign.ValueDisplayFormat = '%.0f';
            app.invLign.Value = 1;
            app.NombredecolonneLabel_3 = uilabel(app.DimensiondelaMatricePanel);
            app.NombredecolonneLabel_3.Text = 'Nombre de  colonne';
            app.invColon = uispinner(app.DimensiondelaMatricePanel);
            app.invColon.Limits = [1 1000];
            app.invColon.RoundFractionalValues = 'on';
            app.invColon.ValueDisplayFormat = '%.0f';
            app.invColon.Value = 1;
            app.Button = uibutton(app.tabMath, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @aideMathButtonPushed, true);
            app.Button.Icon = 'aide.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.tabMath, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @principal_AproposButtonPushed, true);
            app.Button_2.Icon = 'apropos.png';
            app.Button_2.Text = '';
            app.retournAccueilMath = uibutton(app.tabMath, 'push');
            app.retournAccueilMath.ButtonPushedFcn = createCallbackFcn(app, @retournAccueilMathButtonPushed, true);
            app.retournAccueilMath.Text = 'Accueil';
            app.panAccueilMat = uipanel(app.tabMath);
            app.vectResultat_2 = uipanel(app.panAccueilMat);
            app.vectSourceMat_8 = uipanel(app.vectResultat_2);
            app.vectSourceMat_8.Title = 'Je vous souhait la bienvenu dans cette partie';
            app.lab4 = uilabel(app.vectSourceMat_8);
            app.lab4.Text = 'Alors, bonne continuation à vous !';
            app.allerAuSignalBtn = uibutton(app.vectSourceMat_8, 'push');
            app.allerAuSignalBtn.ButtonPushedFcn = createCallbackFcn(app, @allerAuSignalBtnButtonPushed, true);
            app.allerAuSignalBtn.Text = 'Aller au Signal';
            app.matNB = uitextarea(app.vectSourceMat_8);
            app.matNB.Editable = 'off';
            app.matNB.Value = {'        '; ' '; ''; '   Dans cette parite nous alons voir les opérations sur la matrice  en choisissant le menu'; '   pour l''opération par le popup menu ci-dessu             '};
            app.vectSourceMat_4 = uipanel(app.vectResultat_2);
            app.vectSourceMat_4.Title = 'NB';
            app.vectSourceMat_6 = uipanel(app.vectSourceMat_4);
            app.vectSourceMat_6.Title = 'Bouton';
            app.matCloseBtn = uibutton(app.vectSourceMat_6, 'push');
            app.matCloseBtn.ButtonPushedFcn = createCallbackFcn(app, @matCloseBtnButtonPushed, true);
            app.matCloseBtn.Icon = 'sortie.png';
            app.matCloseBtn.Text = ' Quitter';
            app.aproposMath = uibutton(app.vectSourceMat_6, 'push');
            app.aproposMath.ButtonPushedFcn = createCallbackFcn(app, @principal_AproposButtonPushed, true);
            app.aproposMath.Icon = 'apropos.png';
            app.aproposMath.Text = ' A propos';
            app.aideMath = uibutton(app.vectSourceMat_6, 'push');
            app.aideMath.ButtonPushedFcn = createCallbackFcn(app, @aideMathButtonPushed, true);
            app.aideMath.Icon = 'aide.png';
            app.aideMath.Text = ' Aide';
            app.matBienvenu = uitextarea(app.vectSourceMat_4);
            app.matBienvenu.Editable = 'off';
            app.matBienvenu.Value = {' '; ''; ' Cette logiciel n''est pas difficile apprendre mais '; ' juste besoin de connaissance sur la matrice.'; ''; ' Tout le reglement de calcul sur la matrice doivent'; ' etres respectée.'};
            app.vectSourceMat_5 = uipanel(app.panAccueilMat);
            app.vectSourceMat_5.Title = 'PAGE D''ACCUEIL POUR LA PARTIE MATHEMATIQUE';
            app.tabSign = uitab(app.tableauM);
            app.tabSign.Title = 'TRAITEMENT DU SIGNAL                          ';
            app.panMenu = uipanel(app.tabSign);
            app.panMenu.Visible = 'off';
            app.traceFonctionSimple = uibutton(app.panMenu, 'push');
            app.traceFonctionSimple.ButtonPushedFcn = createCallbackFcn(app, @TracagedunesimplefonctionButtonPushed, true);
            app.traceFonctionSimple.Text = 'Traçage d''une simple fonction   ';
            app.transformerFourier = uibutton(app.panMenu, 'push');
            app.transformerFourier.ButtonPushedFcn = createCallbackFcn(app, @transformationEnFourierSimplePushed, true);
            app.transformerFourier.Text = 'Transformation de fourier          ';
            app.traceConvolution = uibutton(app.panMenu, 'push');
            app.traceConvolution.ButtonPushedFcn = createCallbackFcn(app, @traceConvolutionButtonPushed, true);
            app.traceConvolution.Text = 'Traçage de convolution             ';
            app.TracagedunBruit = uibutton(app.panMenu, 'push');
            app.TracagedunBruit.ButtonPushedFcn = createCallbackFcn(app, @TracagedunBruitButtonPushed, true);
            app.TracagedunBruit.Text = 'Traçage d''un signal avec bruit   ';
            app.menuClose = uibutton(app.panMenu, 'push');
            app.menuClose.ButtonPushedFcn = createCallbackFcn(app, @menuCloseButtonPushed, true);
            app.menuClose.Icon = 'retour.png';
            app.menuClose.Text = '';
            app.CloseButton = uibutton(app.panMenu, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Icon = 'sortie.png';
            app.CloseButton.Text = 'Close';
            app.tracageEchelon = uibutton(app.panMenu, 'push');
            app.tracageEchelon.ButtonPushedFcn = createCallbackFcn(app, @tracageEchelonButtonPushed, true);
            app.tracageEchelon.Text = 'Traçage de signe d''un signal     ';
            app.aideSignal = uibutton(app.panMenu, 'push');
            app.aideSignal.ButtonPushedFcn = createCallbackFcn(app, @aideSignalButtonPushed, true);
            app.aideSignal.Icon = 'aide.png';
            app.aideSignal.Text = 'Aide';
            app.Tracagedunsignaldedirac = uibutton(app.panMenu, 'push');
            app.Tracagedunsignaldedirac.ButtonPushedFcn = createCallbackFcn(app, @TracagedunsignaldediracPushed, true);
            app.Tracagedunsignaldedirac.Text = 'Traçage d''un signal de dirac      ';
            app.accueilSignal = uibutton(app.panMenu, 'push');
            app.accueilSignal.ButtonPushedFcn = createCallbackFcn(app, @accueilSignalButtonPushed, true);
            app.accueilSignal.Text = 'Accueil Signal';
            app.apropos = uibutton(app.panMenu, 'push');
            app.apropos.ButtonPushedFcn = createCallbackFcn(app, @principal_AproposButtonPushed, true);
            app.apropos.Icon = 'apropos.png';
            app.apropos.Text = 'A propos';
            app.TraitementdesignalLabel = uilabel(app.tabSign);
            app.TraitementdesignalLabel.Text = 'Traitement de signal';
            app.labTitre = uilabel(app.tabSign);
            app.labTitre.Text = 'Page d''accueil du Signal';
            app.panSimpleFunction = uipanel(app.tabSign);
            app.panSimpleFunction.Visible = 'off';
            app.FonctionEditFieldLabel = uilabel(app.panSimpleFunction);
            app.FonctionEditFieldLabel.Text = 'Fonction';
            app.simpleFonction = uieditfield(app.panSimpleFunction, 'text');
            app.axeSimple = uiaxes(app.panSimpleFunction);
            title(app.axeSimple, 'Title')
            xlabel(app.axeSimple, 'X')
            ylabel(app.axeSimple, 'Y')
            app.axeSimple.LineStyleOrder = {'- '};
            app.axeSimple.LineStyleOrderIndex = 5;
            app.traceSignalSimple = uibutton(app.panSimpleFunction, 'push');
            app.traceSignalSimple.ButtonPushedFcn = createCallbackFcn(app, @traceSignalSimpleButtonPushed, true);
            app.traceSignalSimple.Text = 'Valider';
            app.BornedelavariablePanel = uipanel(app.panSimpleFunction);
            app.BornedelavariablePanel.Title = 'Borne de la variable';
            app.pasSpinnerLabel = uilabel(app.BornedelavariablePanel);
            app.pasSpinnerLabel.Text = 'pas';
            app.pasInterval = uispinner(app.BornedelavariablePanel);
            app.pasInterval.Limits = [0.1 1234560];
            app.pasInterval.ValueDisplayFormat = '%.2f';
            app.pasInterval.Value = 0.5;
            app.infSpinnerLabel = uilabel(app.BornedelavariablePanel);
            app.infSpinnerLabel.Text = 'inf';
            app.bornInf = uispinner(app.BornedelavariablePanel);
            app.bornInf.Limits = [-12345600 123456700];
            app.bornInf.ValueDisplayFormat = '%.0f';
            app.bornInf.Value = -50;
            app.supSpinnerLabel = uilabel(app.BornedelavariablePanel);
            app.supSpinnerLabel.Text = 'sup';
            app.bornSup = uispinner(app.BornedelavariablePanel);
            app.bornSup.Limits = [-12345600 12345600];
            app.bornSup.ValueDisplayFormat = '%.0f';
            app.bornSup.Value = 50;
            app.transformFourier = uipanel(app.tabSign);
            app.transformFourier.Visible = 'off';
            app.FonctionEditFieldLabel_5 = uilabel(app.transformFourier);
            app.FonctionEditFieldLabel_5.Text = 'Fonction';
            app.fourierFunction = uieditfield(app.transformFourier, 'text');
            app.fourierTransformebtn = uibutton(app.transformFourier, 'push');
            app.fourierTransformebtn.ButtonPushedFcn = createCallbackFcn(app, @fourierTransformebtnButtonPushed, true);
            app.fourierTransformebtn.Text = 'Valider';
            app.borneTransFourier = uipanel(app.transformFourier);
            app.borneTransFourier.Title = 'Borne de la variable';
            app.pasSpinnerLabel_5 = uilabel(app.borneTransFourier);
            app.pasSpinnerLabel_5.Text = 'pas';
            app.pasFourier = uispinner(app.borneTransFourier);
            app.pasFourier.Limits = [-123456 1234560];
            app.pasFourier.ValueDisplayFormat = '%.1f';
            app.pasFourier.Value = 0.5;
            app.infSpinnerLabel_5 = uilabel(app.borneTransFourier);
            app.infSpinnerLabel_5.Text = 'inf';
            app.infFourier = uispinner(app.borneTransFourier);
            app.infFourier.Limits = [-12345600 123456700];
            app.infFourier.Value = -10;
            app.supSpinnerLabel_5 = uilabel(app.borneTransFourier);
            app.supSpinnerLabel_5.Text = 'sup';
            app.supFourier = uispinner(app.borneTransFourier);
            app.supFourier.Limits = [-1000 12345600];
            app.supFourier.Value = 10;
            app.optionBtn = uibuttongroup(app.transformFourier);
            app.optionBtn.Title = 'Dimension ';
            app.dimensionSimple = uiradiobutton(app.optionBtn);
            app.dimensionSimple.Text = 'Simple';
            app.dimensionSimple.Value = true;
            app.deuxDimension = uiradiobutton(app.optionBtn);
            app.deuxDimension.Text = '2 Dimension';
            app.nDimension = uiradiobutton(app.optionBtn);
            app.nDimension.Text = 'N Dimension';
            app.axeOrigineFourier = uiaxes(app.transformFourier);
            title(app.axeOrigineFourier, 'Title')
            xlabel(app.axeOrigineFourier, 'X')
            ylabel(app.axeOrigineFourier, 'Y')
            app.axeOrigineFourier.LineStyleOrder = {'- '};
            app.axeOrigineFourier.LineStyleOrderIndex = 5;
            app.axeInversFourier = uiaxes(app.transformFourier);
            title(app.axeInversFourier, 'Title')
            xlabel(app.axeInversFourier, 'X')
            ylabel(app.axeInversFourier, 'Y')
            app.axeInversFourier.LineStyleOrder = {'- '};
            app.axeInversFourier.LineStyleOrderIndex = 5;
            app.axeTransformer = uiaxes(app.transformFourier);
            title(app.axeTransformer, 'Title')
            xlabel(app.axeTransformer, 'X')
            ylabel(app.axeTransformer, 'Y')
            app.axeTransformer.LineStyleOrder = {'- '};
            app.axeTransformer.LineStyleOrderIndex = 5;
            app.signalBruit = uipanel(app.tabSign);
            app.signalBruit.Visible = 'off';
            app.FonctionEditFieldLabel_11 = uilabel(app.signalBruit);
            app.FonctionEditFieldLabel_11.Text = 'Signal';
            app.foncSignalBruit = uieditfield(app.signalBruit, 'text');
            app.traceSignaBruit = uibutton(app.signalBruit, 'push');
            app.traceSignaBruit.ButtonPushedFcn = createCallbackFcn(app, @traceSignaBruitButtonPushed, true);
            app.traceSignaBruit.Text = 'Valider';
            app.bornBruit = uipanel(app.signalBruit);
            app.bornBruit.Title = 'Borne de la variable';
            app.pasSpinnerLabel_10 = uilabel(app.bornBruit);
            app.pasSpinnerLabel_10.Text = 't0';
            app.t0B = uispinner(app.bornBruit);
            app.t0B.Limits = [-123456 1234560];
            app.t0B.ValueDisplayFormat = '%.2f';
            app.t0B.Value = 0.1;
            app.infSpinnerLabel_10 = uilabel(app.bornBruit);
            app.infSpinnerLabel_10.Text = 'Amplitude';
            app.amplitudeB = uispinner(app.bornBruit);
            app.amplitudeB.Limits = [-12345600 123456700];
            app.periodeTBruit = uilabel(app.bornBruit);
            app.periodeTBruit.Text = 'Periode T';
            app.periodeB = uispinner(app.bornBruit);
            app.periodeB.Limits = [-12345600 12345600];
            app.periodeB.Value = 1;
            app.axeSignalAvecBruit = uiaxes(app.signalBruit);
            title(app.axeSignalAvecBruit, 'Title')
            xlabel(app.axeSignalAvecBruit, 'X')
            ylabel(app.axeSignalAvecBruit, 'Y')
            app.axeSignalAvecBruit.LineStyleOrder = {'- '};
            app.axeSignalAvecBruit.LineStyleOrderIndex = 5;
            app.FonctionEditFieldLabel_12 = uilabel(app.signalBruit);
            app.FonctionEditFieldLabel_12.Text = 'Bruit';
            app.foncBruit = uieditfield(app.signalBruit, 'text');
            app.axeResBruit = uiaxes(app.signalBruit);
            title(app.axeResBruit, 'Title')
            xlabel(app.axeResBruit, 'X')
            ylabel(app.axeResBruit, 'Y')
            app.axeResBruit.LineStyleOrder = {'- '};
            app.axeResBruit.LineStyleOrderIndex = 5;
            app.signalDirac = uipanel(app.tabSign);
            app.signalDirac.Visible = 'off';
            app.FonctionEditFieldLabel_13 = uilabel(app.signalDirac);
            app.FonctionEditFieldLabel_13.Text = 'Signal';
            app.foncDirac = uieditfield(app.signalDirac, 'text');
            app.tracerDirac = uibutton(app.signalDirac, 'push');
            app.tracerDirac.ButtonPushedFcn = createCallbackFcn(app, @tracerDiracButtonPushed, true);
            app.tracerDirac.Text = 'Tracer la fonction';
            app.BorneDirac = uipanel(app.signalDirac);
            app.BorneDirac.Title = 'Borne de la variable';
            app.diracLabelT0 = uilabel(app.BorneDirac);
            app.diracLabelT0.Text = 't0';
            app.t0Dirac = uispinner(app.BorneDirac);
            app.t0Dirac.Limits = [-123456 1234560];
            app.t0Dirac.ValueDisplayFormat = '%.2f';
            app.t0Dirac.Value = 1;
            app.amplitudDiracLabel = uilabel(app.BorneDirac);
            app.amplitudDiracLabel.Text = 'Amplitude';
            app.amplitudeDirac = uispinner(app.BorneDirac);
            app.amplitudeDirac.Limits = [-12345600 123456700];
            app.amplitudeDirac.ValueDisplayFormat = '%.1f';
            app.amplitudeDirac.Value = 1;
            app.periodeTDiracLabel = uilabel(app.BorneDirac);
            app.periodeTDiracLabel.Text = 'Periode T';
            app.periodeDirac = uispinner(app.BorneDirac);
            app.periodeDirac.Limits = [-12345600 12345600];
            app.periodeDirac.ValueDisplayFormat = '%.1f';
            app.periodeDirac.Value = 1;
            app.axeDirac = uiaxes(app.signalDirac);
            title(app.axeDirac, 'Title')
            xlabel(app.axeDirac, 'X')
            ylabel(app.axeDirac, 'Y')
            app.axeDirac.LineStyleOrder = {'- '};
            app.axeDirac.LineStyleOrderIndex = 5;
            app.diracOptionBouton = uibuttongroup(app.signalDirac);
            app.diracOptionBouton.SelectionChangedFcn = createCallbackFcn(app, @diracOptionBoutonSelectionChanged, true);
            app.diracOptionBouton.Title = 'Type de Signal';
            app.optionDirac = uiradiobutton(app.diracOptionBouton);
            app.optionDirac.Text = 'Trace de dirac';
            app.optionDirac.Value = true;
            app.optionRectagnle = uiradiobutton(app.diracOptionBouton);
            app.optionRectagnle.Text = 'Trace d''un rectangle';
            app.optionTriangle = uiradiobutton(app.diracOptionBouton);
            app.optionTriangle.Text = 'Trace d''un triangle';
            app.optionEchelon = uiradiobutton(app.diracOptionBouton);
            app.optionEchelon.Text = 'Trace d''échelon';
            app.BorneDirac_2 = uipanel(app.signalDirac);
            app.BorneDirac_2.Title = 'Le notation du signal dans le cas théorie';
            app.amplitudDiracLabel_2 = uilabel(app.BorneDirac_2);
            app.amplitudDiracLabel_2.Text = '   s(t) =  A*dirac(t-t0)    | [  A = amplitude  ]';
            app.amplitudDiracLabel_3 = uilabel(app.BorneDirac_2);
            app.amplitudDiracLabel_3.Text = '   s(t) = A*rect((t-t0)/T)  | [ to = centre ]';
            app.amplitudDiracLabel_4 = uilabel(app.BorneDirac_2);
            app.amplitudDiracLabel_4.Text = '   s(t) = A*tri((t-t0)/T)  | [ to = centre ]';
            app.amplitudDiracLabel_6 = uilabel(app.BorneDirac_2);
            app.amplitudDiracLabel_6.Text = '   s(t) = A*£(t-t0) ';
            app.panConvolution = uipanel(app.tabSign);
            app.panConvolution.Visible = 'off';
            app.rectangleLabelFunc = uilabel(app.panConvolution);
            app.rectangleLabelFunc.Text = 'Signal 1';
            app.foncConv1 = uieditfield(app.panConvolution, 'text');
            app.tracerConvolution = uibutton(app.panConvolution, 'push');
            app.tracerConvolution.ButtonPushedFcn = createCallbackFcn(app, @tracerConvolutionButtonPushed, true);
            app.tracerConvolution.Text = 'Valider';
            app.BorneConvolution = uipanel(app.panConvolution);
            app.BorneConvolution.Title = 'Borne de la variable';
            app.rectangleLabelT0 = uilabel(app.BorneConvolution);
            app.rectangleLabelT0.Text = 'Frequence';
            app.freqConv = uispinner(app.BorneConvolution);
            app.freqConv.Limits = [1 1234560];
            app.freqConv.ValueDisplayFormat = '%.1f';
            app.freqConv.Value = 100;
            app.amplitudRectangleLabel = uilabel(app.BorneConvolution);
            app.amplitudRectangleLabel.Text = 'Inf';
            app.infConv = uispinner(app.BorneConvolution);
            app.infConv.Limits = [-12345600 123456700];
            app.infConv.Value = -10;
            app.periodeRectangleLabel = uilabel(app.BorneConvolution);
            app.periodeRectangleLabel.Text = 'Sup';
            app.supConv = uispinner(app.BorneConvolution);
            app.supConv.Limits = [-12345600 12345600];
            app.supConv.Value = 10;
            app.rectangleLabelFunc_2 = uilabel(app.panConvolution);
            app.rectangleLabelFunc_2.Text = 'Signal 2';
            app.foncConv2 = uieditfield(app.panConvolution, 'text');
            app.axeConvolution = uiaxes(app.panConvolution);
            title(app.axeConvolution, 'Title')
            xlabel(app.axeConvolution, 'X')
            ylabel(app.axeConvolution, 'Y')
            app.axeConvolution.LineStyleOrder = {'- '};
            app.axeConvolution.LineStyleOrderIndex = 5;
            app.panSign = uipanel(app.tabSign);
            app.panSign.Visible = 'off';
            app.functionPeriodique_2 = uilabel(app.panSign);
            app.functionPeriodique_2.Text = 'Fonction';
            app.fonctionSign = uieditfield(app.panSign, 'text');
            app.signBoutonValider = uibutton(app.panSign, 'push');
            app.signBoutonValider.ButtonPushedFcn = createCallbackFcn(app, @signBoutonValiderButtonPushed, true);
            app.signBoutonValider.Text = 'Tracer';
            app.signOption = uidropdown(app.panSign);
            app.signOption.Items = {'sin(t)', 'sinc(t)', 'tan(t)'};
            app.signOption.ValueChangedFcn = createCallbackFcn(app, @signOptionValueChanged, true);
            app.signOption.Value = 'sin(t)';
            app.indication_de_sign = uipanel(app.panSign);
            app.indication_de_sign.Title = 'Indication de la traçage d''un signe';
            app.LatraagedesignedunsgnalsefaitcommececiLabel = uilabel(app.indication_de_sign);
            app.LatraagedesignedunsgnalsefaitcommececiLabel.Text = 'La traçage de signe d''un sgnal se fait comme ceci ';
            app.stsignt1sit0etLabel = uilabel(app.indication_de_sign);
            app.stsignt1sit0etLabel.Text = 's(t) =  sign(t)  =  -1    si t < 0    et';
            app.stsignt1sit0Label = uilabel(app.indication_de_sign);
            app.stsignt1sit0Label.Text = 's(t) =  sign(t)  =  1    si t > 0    ';
            app.axeSign = uiaxes(app.panSign);
            title(app.axeSign, 'Title')
            xlabel(app.axeSign, 'X')
            ylabel(app.axeSign, 'Y')
            app.axeSign.LineStyleOrder = {'- '};
            app.axeSign.LineStyleOrderIndex = 5;
            app.menuShow = uibutton(app.tabSign, 'push');
            app.menuShow.ButtonPushedFcn = createCallbackFcn(app, @menuShowButtonPushed, true);
            app.menuShow.Icon = 'icons8_windows8_48px.png';
            app.menuShow.Text = '';
            app.panAccueilS = uipanel(app.tabSign);
            app.titreSignal = uipanel(app.panAccueilS);
            app.titreSignal.Title = 'Partie de Traitement de Signal';
            app.contenu2S = uipanel(app.panAccueilS);
            app.closeSignal = uibutton(app.contenu2S, 'push');
            app.closeSignal.ButtonPushedFcn = createCallbackFcn(app, @closeSignalButtonPushed, true);
            app.closeSignal.Icon = 'sortie.png';
            app.closeSignal.Text = 'Quitter';
            app.allerMath = uibutton(app.contenu2S, 'push');
            app.allerMath.ButtonPushedFcn = createCallbackFcn(app, @allerMathButtonPushed, true);
            app.allerMath.Icon = 'showMenu.png';
            app.allerMath.Text = 'Aller au Mathématique';
            app.signRetourneAccueil = uibutton(app.contenu2S, 'push');
            app.signRetourneAccueil.ButtonPushedFcn = createCallbackFcn(app, @signRetourneAccueilButtonPushed, true);
            app.signRetourneAccueil.Icon = 'accueil.png';
            app.signRetourneAccueil.Text = ' Accueil Principal';
            app.labTitre_14 = uilabel(app.contenu2S);
            app.labTitre_14.Text = 'Copyright Tous droit réservés nomome 10.    Version : 1.03.14.2021             ';
            app.contenu1S = uipanel(app.panAccueilS);
            app.contenu1S.Title = 'Je vous souhait la bienvenu de cette partie';
            app.vectSourceMat_9 = uipanel(app.contenu1S);
            app.vectSourceMat_9.Title = 'Indication sur le traçage de signal';
            app.lab4_3 = uilabel(app.vectSourceMat_9);
            app.lab4_3.Text = 'Alors, bonne continuation à vous !';
            app.signalIndication = uitextarea(app.vectSourceMat_9);
            app.signalIndication.Editable = 'off';
            app.signalIndication.Value = {' '; '                Utiliser l''icone windows en haut à gauche pour voir les différentes de traçage d''un signal '; '                Sur le traçage des signals, vous devez utiliser un seul unique variable pour qu''il soit bien fonctionnée.'; '                Utiliser le caractère [ t ] pour le nom du variable'};
            app.bienvenuAccueil = uitextarea(app.contenu1S);
            app.bienvenuAccueil.Editable = 'off';
            app.bienvenuAccueil.Value = {''; '                 Le traitement de signal est une representation graphique des informations par une fonction'; '                 Lors de transmission de ces informations il existe un bruit qui perturbe le decodage de ces données avant '; '                 d''arrivé au destinateur, mais il y a un autre signal aussi appelée bruit qui peut etre embrouillé le signal utile '; '                 qui porte des informations et ce pour cela qu''on l''intervienne pour traiter ces signal'; ''};
            app.tabImg = uitab(app.tableauM);
            app.tabImg.Title = 'TRAITEMENT D''IMAGE';
            app.accueil = uipanel(app.fen);
            app.accueilP = uipanel(app.accueil);
            app.labAccueil = uilabel(app.accueilP);
            app.labAccueil.Text = 'Je vous souhaite la bienvenu sur cette projet';
            app.panT = uipanel(app.accueilP);
            app.panT.Title = 'Page d''accueil';
            app.modUtil = uipanel(app.accueilP);
            app.modUtil.Title = 'Utilisation du logiciel';
            app.lab1_2 = uilabel(app.modUtil);
            app.lab1_2.Text = '   Cette logiciel n''est pas difficile apprendre mais juste besoin de connaissance sur';
            app.lab2_2 = uilabel(app.modUtil);
            app.lab2_2.Text = '   la matrice , je veux dire, tous le reglement  de calcul sur la matrice doivent etres';
            app.lab4_2 = uilabel(app.modUtil);
            app.lab4_2.Text = 'Alors, bonne continuation à vous !';
            app.lab3_2 = uilabel(app.modUtil);
            app.lab3_2.Text = '   respectée et pareil pour le traçage d''un signal.';
            app.versionLog = uilabel(app.modUtil);
            app.versionLog.Text = 'Copyright tous droit réserver : 01.03.21.00.32.52                ';
            app.principal_Aller_au_Math = uibutton(app.modUtil, 'push');
            app.principal_Aller_au_Math.ButtonPushedFcn = createCallbackFcn(app, @allerMathButtonPushed, true);
            app.principal_Aller_au_Math.Icon = 'showMenu.png';
            app.principal_Aller_au_Math.Text = 'Aller au Mathématique';
            app.principalAller_auSignal = uibutton(app.modUtil, 'push');
            app.principalAller_auSignal.ButtonPushedFcn = createCallbackFcn(app, @allerAuSignalBtnButtonPushed, true);
            app.principalAller_auSignal.Icon = 'deconnect.png';
            app.principalAller_auSignal.Text = 'Aller au Signal';
            app.principal_Aide = uibutton(app.modUtil, 'push');
            app.principal_Aide.ButtonPushedFcn = createCallbackFcn(app, @principal_AideButtonPushed, true);
            app.principal_Aide.Icon = 'aide.png';
            app.principal_Aide.Text = ' Aide';
            app.principal_Apropos = uibutton(app.modUtil, 'push');
            app.principal_Apropos.ButtonPushedFcn = createCallbackFcn(app, @principal_AproposButtonPushed, true);
            app.principal_Apropos.Icon = 'apropos.png';
            app.principal_Apropos.Text = ' A propos';
            app.quitPrincipalBtn = uibutton(app.modUtil, 'push');
            app.quitPrincipalBtn.ButtonPushedFcn = createCallbackFcn(app, @quitPrincipalBtnButtonPushed, true);
            app.quitPrincipalBtn.Icon = 'sortie.png';
            app.quitPrincipalBtn.Text = 'Quitter';
            app.titre = uilabel(app.accueil);
            app.titre.Text = 'PROJET UTILITAIRE POUR IMAGE AVEC LE TRAITEMENT DE  SIGNAL ET DU MATRICE ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = projet_utilitaire

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fen)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fen)
        end
    end
end